classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        mask_erosion_size               matlab.ui.control.NumericEditField
        erodesizeLabel                  matlab.ui.control.Label
        RefinemaskButton                matlab.ui.control.Button
        UpdatestackButton               matlab.ui.control.Button
        ImageScaleEditField             matlab.ui.control.NumericEditField
        ImagereductionXfoldEditFieldLabel  matlab.ui.control.Label
        ZrangeLabel                     matlab.ui.control.Label
        LoadnucleusmasksButton          matlab.ui.control.Button
        cell_mask_channel               matlab.ui.control.NumericEditField
        maskchannelLabel                matlab.ui.control.Label
        SegmentTypeButtonGroup          matlab.ui.container.ButtonGroup
        spotsButton                     matlab.ui.control.ToggleButton
        fociButton                      matlab.ui.control.ToggleButton
        nucleiButton                    matlab.ui.control.ToggleButton
        subsampleCheckBox               matlab.ui.control.CheckBox
        ChannelEditField                matlab.ui.control.NumericEditField
        ChannelEditFieldLabel           matlab.ui.control.Label
        RESETsegButton                  matlab.ui.control.Button
        z_range_high                    matlab.ui.control.NumericEditField
        z_range_low                     matlab.ui.control.NumericEditField
        ButtonGroup                     matlab.ui.container.ButtonGroup
        DButton_2                       matlab.ui.control.ToggleButton
        DButton                         matlab.ui.control.ToggleButton
        TimeindexEditField              matlab.ui.control.NumericEditField
        TimeindexEditFieldLabel         matlab.ui.control.Label
        ForwardButton                   matlab.ui.control.Button
        BackButton                      matlab.ui.control.Button
        CurrentStepsListBox             matlab.ui.control.ListBox
        CurrentStepsListBoxLabel        matlab.ui.control.Label
        SaveParametersButton            matlab.ui.control.Button
        DispaylayerButtonGroup          matlab.ui.container.ButtonGroup
        BWButton                        matlab.ui.control.ToggleButton
        FilteredButton                  matlab.ui.control.ToggleButton
        ImageButton                     matlab.ui.control.ToggleButton
        TabGroup                        matlab.ui.container.TabGroup
        NucleifociTab                   matlab.ui.container.Tab
        SubcellularsegmentationfociPanel  matlab.ui.container.Panel
        NucleusintensitythresholdEditField  matlab.ui.control.NumericEditField
        NucleusintensitythresholdEditFieldLabel  matlab.ui.control.Label
        FociSizeMax                     matlab.ui.control.NumericEditField
        FociSizeMin                     matlab.ui.control.NumericEditField
        SizefilterpxLabel               matlab.ui.control.Label
        ClipprctilerangeUpper           matlab.ui.control.NumericEditField
        ClipprctilerangeLower           matlab.ui.control.NumericEditField
        ClipprctilerangeEditFieldLabel  matlab.ui.control.Label
        SNRthreshold                    matlab.ui.control.NumericEditField
        SNRthresholdEditFieldLabel      matlab.ui.control.Label
        SubregionSegmentationButton     matlab.ui.control.Button
        ThresholdingButtonGroup         matlab.ui.container.ButtonGroup
        intLabel_2                      matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        intLabel                        matlab.ui.control.Label
        dropfactorEditField             matlab.ui.control.NumericEditField
        dropfactorEditFieldLabel        matlab.ui.control.Label
        Localseg_Button                 matlab.ui.control.Button
        PercentileButton                matlab.ui.control.Button
        SimpleButton                    matlab.ui.control.Button
        Localseg_hdepth_value           matlab.ui.control.NumericEditField
        Percentile_value                matlab.ui.control.NumericEditField
        SimpleThresh_value              matlab.ui.control.NumericEditField
        DiffusionGradientfilteringPanel  matlab.ui.container.Panel
        SigmaGradientFilterpxPanel      matlab.ui.container.Panel
        zEditField_2                    matlab.ui.control.NumericEditField
        zEditField_2Label               matlab.ui.control.Label
        xyEditField_2                   matlab.ui.control.NumericEditField
        xyEditField_2Label              matlab.ui.control.Label
        MedianFilterpxPanel             matlab.ui.container.Panel
        zEditField                      matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        xyEditField                     matlab.ui.control.NumericEditField
        xyEditFieldLabel                matlab.ui.control.Label
        GammaEditField                  matlab.ui.control.NumericEditField
        GammaEditFieldLabel             matlab.ui.control.Label
        DeltaEditField                  matlab.ui.control.NumericEditField
        DeltaEditFieldLabel             matlab.ui.control.Label
        EpsilonEditField                matlab.ui.control.NumericEditField
        EpsilonEditFieldLabel           matlab.ui.control.Label
        BetaEditField                   matlab.ui.control.NumericEditField
        BetaEditFieldLabel              matlab.ui.control.Label
        AlphaEditField                  matlab.ui.control.NumericEditField
        AlphaEditFieldLabel             matlab.ui.control.Label
        gradientimageButton             matlab.ui.control.Button
        PostprocessingPanel             matlab.ui.container.Panel
        max_sphericity                  matlab.ui.control.NumericEditField
        maxsphericityLabel              matlab.ui.control.Label
        mergedistEditField              matlab.ui.control.NumericEditField
        mergedistEditFieldLabel         matlab.ui.control.Label
        MergerButton                    matlab.ui.control.StateButton
        pxLabel_3                       matlab.ui.control.Label
        FilterVolsButton                matlab.ui.control.Button
        VolumehistogramButton           matlab.ui.control.Button
        AbsMaxVol                       matlab.ui.control.NumericEditField
        AbsMinVol                       matlab.ui.control.NumericEditField
        VolumesEditFieldLabel           matlab.ui.control.Label
        WaterShedMaxVol                 matlab.ui.control.NumericEditField
        maxvolumeEditFieldLabel         matlab.ui.control.Label
        hmindepthEditField              matlab.ui.control.NumericEditField
        hmindepthEditFieldLabel         matlab.ui.control.Label
        Watershed                       matlab.ui.control.Button
        FillholesButton                 matlab.ui.control.Button
        pxLabel_2                       matlab.ui.control.Label
        pxLabel                         matlab.ui.control.Label
        FilterOutliersButton            matlab.ui.control.Button
        ClosefilterButton               matlab.ui.control.Button
        outlier_threshold_value         matlab.ui.control.NumericEditField
        imclose_radius_value            matlab.ui.control.NumericEditField
        PreprocessrawimagePanel         matlab.ui.container.Panel
        CLAHEChkbox                     matlab.ui.control.CheckBox
        ClipLimitEditField              matlab.ui.control.NumericEditField
        ClipLimitEditFieldLabel         matlab.ui.control.Label
        TileSizeEditField               matlab.ui.control.NumericEditField
        TileSizeEditFieldLabel          matlab.ui.control.Label
        CLAHEButton                     matlab.ui.control.Button
        background_value                matlab.ui.control.NumericEditField
        SubbackgroundButton             matlab.ui.control.Button
        SubBackChkbox                   matlab.ui.control.CheckBox
        SensitivityEditField            matlab.ui.control.NumericEditField
        SensitivityEditFieldLabel       matlab.ui.control.Label
        mean_filter_z_value             matlab.ui.control.NumericEditField
        zLabel                          matlab.ui.control.Label
        mean_filter_xy_value            matlab.ui.control.NumericEditField
        xyEditField_3Label              matlab.ui.control.Label
        MeanfilterButton                matlab.ui.control.Button
        layer_choice_panel              matlab.ui.container.Panel
        filteredCheckBox                matlab.ui.control.CheckBox
        rawCheckBox                     matlab.ui.control.CheckBox
        SpotsTab                        matlab.ui.container.Tab
        PlotStatsButton                 matlab.ui.control.Button
        SpotsizefilteringButtonGroup    matlab.ui.container.ButtonGroup
        maxLabel                        matlab.ui.control.Label
        spot_size_ub                    matlab.ui.control.NumericEditField
        minLabel                        matlab.ui.control.Label
        spot_size_lb                    matlab.ui.control.NumericEditField
        FiltervolumeButton              matlab.ui.control.Button
        sizehistogramButton             matlab.ui.control.Button
        SpotfittingButtonGroup          matlab.ui.container.ButtonGroup
        spot_search_radius              matlab.ui.control.NumericEditField
        searchradiusLabel               matlab.ui.control.Label
        spot_bg_max                     matlab.ui.control.NumericEditField
        backgroundLabel                 matlab.ui.control.Label
        spot_bg_min                     matlab.ui.control.NumericEditField
        ArbitraryblobButton             matlab.ui.control.Button
        GaussianButton                  matlab.ui.control.Button
        snr_threshold                   matlab.ui.control.NumericEditField
        SNRthresholdButton              matlab.ui.control.Button
        max_sphericity_box              matlab.ui.control.NumericEditField
        min_sphericity_box              matlab.ui.control.NumericEditField
        SphericityhistogramButton       matlab.ui.control.Button
        SphericityfilterButton          matlab.ui.control.Button
        merge_distance                  matlab.ui.control.NumericEditField
        distancepxEditFieldLabel        matlab.ui.control.Label
        SubtractlocalbackgroundCheckBox  matlab.ui.control.CheckBox
        spot_log_sigma_z                matlab.ui.control.NumericEditField
        zLabel_3                        matlab.ui.control.Label
        spot_log_sigma_xy               matlab.ui.control.NumericEditField
        xyEditField_3Label_3            matlab.ui.control.Label
        MergeduplicatesButton           matlab.ui.control.Button
        SpotthresholdingButtonGroup     matlab.ui.container.ButtonGroup
        PercentilesLabel                matlab.ui.control.Label
        filteredLabel                   matlab.ui.control.Label
        rawLabel                        matlab.ui.control.Label
        LocalButton                     matlab.ui.control.Button
        SimpleButton_2                  matlab.ui.control.Button
        spot_percentile_filtered        matlab.ui.control.NumericEditField
        spot_percentile_img             matlab.ui.control.NumericEditField
        spot_simple_threshold           matlab.ui.control.NumericEditField
        LogfilterButton                 matlab.ui.control.Button
        spot_sigma_z                    matlab.ui.control.NumericEditField
        zLabel_2                        matlab.ui.control.Label
        spot_sigma_xy                   matlab.ui.control.NumericEditField
        xyEditField_3Label_2            matlab.ui.control.Label
        PresmoothButton                 matlab.ui.control.Button
        TrackCellsTab                   matlab.ui.container.Tab
    end

    
    properties (Access = public )
        reader
        image_bits
        channel
        Image
        Filtered % filtered image
        BW% current binarized image
        stats
        curr_z
        curr_t
        obj % track_analyzer object
        %update_img=@update_img
        %update_stk=@update_stack
        toolboxApp
        view_layer
        img_dim
        seg_params=struct();
        seg_steps
        frame_obj
        spot_stats
        cell_mask_channel_str
        cell_bw
        seg_type % cells vs spots. 
        S % segmenter. 
        viewer
    end
    
    properties (Access = private)
   
        Property20 % Description
    end
    
    methods (Access = public)
        
        function update_image(app)
            %Check if viewer was deleted? 
            isDeletedObj = @(h)isobject(h) & ~isgraphics(h);
            if isDeletedObj(app.viewer)
                app.viewer = viewer(zeros(1024,1024,10),'filter_on',false);
            end
            app.viewer.update_stack(app.S.(app.view_layer),app.S.BW,app.S.mask);
            app.viewer.ThresholdCheckBox.Value = false;
        end

        function update_image_filter(app)
            isDeletedObj = @(h)isobject(h) & ~isgraphics(h);
            %Check if viewer was deleted? 
            if isDeletedObj(app.viewer)
                app.viewer = viewer(zeros(1024,1024,10),'filter_on',false);       
            end
            app.viewer.update_stack(app.S.(app.view_layer),app.S.BW,app.S.mask);
            app.viewer.ThresholdCheckBox.Value = true;
            app.viewer.ThresholdEditField.Value = prctile(app.S.(app.view_layer)(:),99.5);

        end
        
        
        function update_stack(app)
            
            img = get_stack(app.reader, app.curr_t, app.channel);
            
            if app.img_dim == 2 & length(size(img))==3
                z_vals = [app.z_range_low.Value:app.z_range_high.Value];
                img = max(img(:,:,z_vals),[],3);
            end
            
            % Sub-sample image if requested - image could be very large. 
            if app.subsampleCheckBox.Value
                img = img(1:1024,1:1024,:);
            end
            
            % Reduce image dimensions if needed. 
            img_scale=app.ImageScaleEditField.Value;
            app.seg_params.(app.channel_str).(app.seg_type).img_scale=img_scale;
            if img_scale > 1
                img = imresize3(img, 1/img_scale);
            end   
            display(["New image size ", string(size(img))])
            % Initialize segmenter object. Resets images. 
            params = app.seg_params;
            app.S = segmenter(img,app.image_bits,params,app.curr_t,app.seg_type);
            app.view_layer='img';
            update_image(app)
        end
   
        function return_str = channel_str(app)
        
            return_str = ['channel_', pad(num2str(app.channel), 2, 'left', '0')];
            
        end
        
        
        function params = get_seg_params(app)
            params = app.seg_params.(app.channel_str).(app.seg_type);
        end
        
        function store_seg_params(app, params)
            app.seg_params.(app.channel_str).(app.seg_type) = params;
        end
    end
    
    methods (Access = private)
        
        function add_step(app, this_step, this_value)
            
            % determine index of the step structure. 
            if isempty(app.seg_steps(1).type)
                index = 1;
            else
                %Check if previous step is same. If not, then append. 
                previous_step = app.seg_steps(end).type;
                
                if strcmp(previous_step, this_step)
                    index = length(app.seg_steps);
                else
                    index = length(app.seg_steps) + 1;
                end
            end
    
            % add step to structure. 
            app.seg_steps(index).type = this_step;
            
            % add value if supplied to function
            if exist("this_value")
                app.seg_steps(index).value = this_value;
            end


            %Update text box. 
            update_seg_steps(app)

        end
        
        function initialize_seg_steps(app)
%             steps={'subtract_background',
%             'clahe',
%             'diff_gradients',
%             'thresholding',
%             'filter_objects',
%             'close_filter',
%             'filter_outliers',
%             'fill_holes',
%             'filter_objects',
%             'watershed',
%             'merge',
%             'filter_rg',
%             'add_mask'};
        
            app.seg_steps = struct('type',[],'value',[]);
            app.update_seg_steps();
%             % Fill in steps. 
%             for i = 1:length(out_steps)
%             
%                 out_steps(i).type=steps{i};
%                 out_steps(i).value=0;
%             
%             end
        end
            
        function initialize_seg_params(app, T)
            
            % Get default seg_parameters for this type of segmentation. 
            % App.seg_params is a structure with 'cell','foci','spots'
            % fields, and each of these will have a channel field. 
            seg_params = default_params(app.seg_type, T);

            % save as app.seg_params.(app.channel_str).(app.seg_type)
            % Now check if existing parameters
            if isfield(app.obj.exp_info,'params')
                if isfield(app.obj.exp_info.params,app.channel_str)
                    try
                        % add parameters (one-by-one in case some aren't defined. 
                        for p = fieldnames(app.obj.exp_info.params.(app.channel_str).(app.seg_type))'
                            seg_params.(p{1})=app.obj.exp_info.params.(app.channel_str).(app.seg_type).(p{1});
                        end
                    catch
                        warning('No seg params defined for this channel/seg type')
                    end
                end
            end
            
            % Set gui fields. 
            
            switch app.seg_type
                
                case {'cells','foci'}
                    
                    app.background_value.Value = seg_params.bg;
                    app.TileSizeEditField.Value = seg_params.tile_size;
                    app.ClipLimitEditField.Value = seg_params.ClipLimit;
                    
                    app.AlphaEditField.Value = seg_params.alpha;
                    app.BetaEditField.Value= seg_params.beta;
                    app.GammaEditField.Value= seg_params.gamma;
                    app.DeltaEditField.Value= seg_params.delta;
                    app.EpsilonEditField.Value= seg_params.epsilon;
                    app.xyEditField_2.Value = seg_params.sigmagradient(1);
                    app.zEditField_2.Value =  seg_params.sigmagradient(3);
                    
                    app.xyEditField.Value= seg_params.median_filter(1);
                    app.zEditField.Value = seg_params.median_filter(3);
        
                    app.SimpleThresh_value.Value= seg_params.simple_threshold(app.curr_t);
                    app.Percentile_value.Value= seg_params.percentile(app.curr_t);
                    app.Localseg_hdepth_value.Value= seg_params.percentile(app.curr_t);
                    % Now tack on a filtering step. 
                    app.AbsMinVol.Value= seg_params.AbsMinVol;
                    app.AbsMaxVol.Value= seg_params.AbsMaxVol;
                    app.imclose_radius_value.Value= seg_params.imclose_r;
                    app.SensitivityEditField.Value= seg_params.MeanFilterSensitivity;
                    app.mean_filter_xy_value.Value= seg_params.MeanFilterNeighborhood(1);
                    try
                        app.mean_filter_z_value.Value = seg_params.MeanFilterNeighborhood(3); 
                    catch
                        app.mean_filter_z_value.Value=0;
                    end
        
                    app.outlier_threshold_value.Value   = seg_params.OutlierThreshold;
                    app.hmindepthEditField.Value        = seg_params.h_min_depth;
                    app.mergedistEditField.Value        = seg_params.merge_dist;
        
                    app.Localseg_hdepth_value.Value = seg_params.Hdepth;
                    app.WaterShedMaxVol.Value       = seg_params.WaterShedMaxVol;
                    
                    app.ImageScaleEditField.Value   = seg_params.img_scale;

                case 'spots'
                    
                    app.spot_sigma_xy.Value             = seg_params.smooth_sigma(1);
                    app.spot_sigma_z.Value              = seg_params.smooth_sigma(3);
                    app.spot_log_sigma_xy.Value         = seg_params.spot_sigma(1);
                    app.spot_log_sigma_z.Value          = seg_params.spot_sigma(3);
                    app.spot_percentile_img.Value       = seg_params.local_thresh_percentile(1);
                    app.spot_percentile_filtered.Value  = seg_params.local_thresh_percentile(2);
                    app.ImageScaleEditField.Value   = seg_params.img_scale;

            end
    
            % Store seg params into app. 
            app.store_seg_params(seg_params);
           
        end


        % Update seg_steps. 
        function update_seg_steps(app)
        
            if ~isempty(app.seg_steps(1).type)
                
                if length(app.seg_steps)==1
                    app.CurrentStepsListBox.Items=cellstr(app.seg_steps.type);
                else
                    app.CurrentStepsListBox.Items=cellstr(strvcat(app.seg_steps.type));
                end
                
            else
                app.CurrentStepsListBox.Items={};
            end
            
        end
        
        % update parameters to segmentation object. Segmenter object
        % expects "params" is a structure with all parameters forstartup
        % cell-style segmentation (i.e. not spots). 
        function update_seg_params(app)
            app.S.params = app.seg_params.(app.channel_str).(app.seg_type);
        end
        
        % Returns which layer to use (i.e. raw/pre-processed OR filtered)
        function use_layer = get_layer_choice(app)
            % update which image to perform filtering on. 
            if (app.filteredCheckBox.Value && app.rawCheckBox.Value) || (app.filteredCheckBox.Value==false && app.rawCheckBox.Value==false)

                message = {'Must select either filtered or raw!'};
                        uialert(app.UIFigure,message,'Warning',...
                        'Icon','warning');
                return
            elseif app.filteredCheckBox.Value

                use_layer='filtered';

            elseif app.rawCheckBox.Value

                use_layer='img';
               
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, obj, step)
            
            if nargin < 3
                step.use_max_p=0;
            end

            % initialize time and channels. 
            app.curr_t=1;
            app.channel=1;
            app.curr_z=1;
            app.obj=obj;

            
            %if channel is available: 
            if nargin == 3
                app.channel=step.channel;
                app.ImageScaleEditField.Value=step.scale;
            end
            if ~isfield(step,'use_max_p')
                step.use_max_p = 0;
            end
            %Set channel string box value 
            app.ChannelEditField.Value=app.channel;
            % Initialize default segmentation parameters. Set seg-type as cells. 
            app.seg_type='cells';
            app.initialize_seg_params(app.obj.exp_info.t_frames);
            app.initialize_seg_steps();
            
            %set scaling AFTER initialize parameters 
            if nargin == 3
                app.ImageScaleEditField.Value=step.scale;
            end
            drawnow
            
            %Which image file?
            if step.use_max_p
                img_file_name=obj.exp_info.max_p_img;
            else
                img_file_name=obj.exp_info.img_file;
            end
            

            % Add image reader to app
            app.reader = get_memo_reader(img_file_name);
            app.image_bits=app.reader.getBitsPerPixel;   

            app.view_layer='img';
            
            % Check if there are any existing segmented nuclei/cells. This
            % usually means we should switch to 'foci' or 'spots'. 
            F = obj.get_frame_files;
            if isfile(F{1})
                load(F{1});
            end


            %Initialize image viewer with empty image. 
            app.viewer = viewer(zeros(1024,1024,10),'filter_on',false);
            
                
            update_stack(app);
            app.img_dim = length(size(app.S.img));
            
            switch app.img_dim
                case 2
                    app.ButtonGroup.SelectedObject=app.DButton;
                    app.z_range_high.Value=1;
                case 3
                    app.ButtonGroup.SelectedObject=app.DButton_2;
                    app.z_range_high.Value=obj.exp_info.z_planes;
            end

            % Initialize step box. 
            % Call diaglog app
            %app.toolboxApp=tool_box(app);
        end

        % Callback function
        function t_change(app, event)
            app.curr_t = app.TimeSlider.Value;
            update_stack(app)
        end

        % Callback function: DispaylayerButtonGroup, DispaylayerButtonGroup
        function layer_change(app, event)
            value = app.DispaylayerButtonGroup.SelectedObject.Text;
            
            % Figure out which layer to display. 
            switch value
                
                case 'Image'
                    
                    app.view_layer='img';
                    % Set current layer. 
                    app.update_image();
                case 'Filtered'
                    % Check if filtered exists yet. 
                    if isempty(app.S.filtered)
                        message = {'No filtered image available yet!'};
                        uialert(app.UIFigure,message,'Warning',...
                        'Icon','warning');
                        app.DispaylayerButtonGroup.SelectedObject=app.ImageButton;
                    else
                        app.view_layer='filtered';
                    end
                    app.update_image_filter
                case 'BW'      
                    % Check if BW exists yet. 
                    if isempty(app.S.BW)
                        app.view_layer='img';
                    else
                        app.view_layer='BW';
                    end   
                    % Set current layer. 
                    app.update_image();
            end

            
        end

        % Button pushed function: RESETsegButton
        function reset_button(app, event)
            app.initialize_seg_steps;
            app.initialize_seg_params(app.curr_t);
            %app.update_stack();
        end

        % Button pushed function: MeanfilterButton
        function mean_filter_button(app, event)
            params = app.get_seg_params;

            params.MeanFilterSensitivity=app.SensitivityEditField.Value;
            % Check dimensions. 
            switch app.img_dim
                
                case 2
                    params.MeanFilterNeighborhood= app.mean_filter_xy_value.Value;
                    
                case 3
                    params.MeanFilterNeighborhood= [app.mean_filter_xy_value.Value,app.mean_filter_xy_value.Value,app.mean_filter_z_value.Value];

            end

            %Store params to app. 
            app.store_seg_params(params);

            %Update segmenter params. 
            app.update_seg_params()
            d = uiprogressdlg(app.UIFigure,'Title','Performing mean filter',...
                'Indeterminate','on');
            % Apply task. 
            app.S.mean_filter();
            
            app.add_step('mean_filter');
            app.update_image();
            close(d);
        end

        % Button pushed function: SubbackgroundButton
        function sub_background_button(app, event)
            params = app.get_seg_params();
            % Subtract background. 
            params.bg = app.background_value.Value;
            app.store_seg_params(params);

            % update S. 
            app.S.params = params;

            % Run task.
            app.S.subtract_background();
            app.update_image();
            
            % Record step
            app.add_step('subtract_background');

        end

        % Button pushed function: CLAHEButton
        function clahe_button(app, event)
            params = app.get_seg_params;
            params.tile_size = app.TileSizeEditField.Value;
            params.ClipLimit = app.ClipLimitEditField.Value;
            app.store_seg_params(params);
            
            % update S.
            app.S.params = params;
            app.S.clahe();
            app.update_image;

            % Record step
            app.add_step('clahe');

        end

        % Button pushed function: gradientimageButton
        function gradient_button(app, event)
            params = app.get_seg_params;
            % Fix kappa?
            params.kappa1=10;
            params.kappa2=0;
            params.option=1;
            params.diffuse_iterations=5;
            % Get parameters. 
            params.alpha=app.AlphaEditField.Value;
            params.beta=app.BetaEditField.Value;
            params.gamma=app.GammaEditField.Value;
            params.delta=app.DeltaEditField.Value;
            params.epsilon=app.EpsilonEditField.Value;
            params.sigmagradient = [app.xyEditField_2.Value,app.xyEditField_2.Value,app.zEditField_2.Value];
            params.median_filter = [app.xyEditField.Value,app.xyEditField.Value,app.zEditField.Value];
            
            d = uiprogressdlg(app.UIFigure,'Title','Computing gradients',...
                'Indeterminate','on');
            
            app.store_seg_params(params);
            
            %update S
            app.S.params = params;
            %run task
            app.S.diff_gradients();

            app.view_layer = 'filtered';
            app.update_image_filter;

            % Record step. 
            app.add_step('diff_gradients');
     
            % close the dialog box
            close(d);
            app.DispaylayerButtonGroup.SelectedObject=app.FilteredButton;
        end

        % Callback function: Localseg_Button, PercentileButton, 
        % ...and 2 other components
        function threshold_button(app, event)
            selectedButton = event.Source.Text;
            
            % Check if last step was thresholding. 
            types={'simple_thresholding','hist_thresholding','adaptive_thresholding'};
            % Check if any previous steps were thresholding.
            next=length(app.seg_steps)+1;
            for s = 1:length(app.seg_steps)
                if any( strcmp(types, app.seg_steps(s).type ))
                    next = s;
                    app.seg_steps = app.seg_steps(1:s-1);
                    break
                end
            end
            
            params = app.get_seg_params;
            
            %Update volume bounds. 
            params.AbsMinVol = app.AbsMinVol.Value;
            params.AbsMaxVol = app.AbsMaxVol.Value;
                 
            % Get layer choice. 
            params.use_layer = app.get_layer_choice();

            d = uiprogressdlg(app.UIFigure,'Title','Thresholding',...
                'Indeterminate','on');

            % If we need don't need the filtered image, then make sure
            % checkbox is switched. 
            if isempty( app.S.(params.use_layer))
                error('Image data is empty. Perhaps change checkbox?')
            end


            switch selectedButton
                
                case 'Simple'
                    
                    params.simple_threshold(app.curr_t) = app.SimpleThresh_value.Value;
                    
                    %update S, run task. 
                    app.S.params = params;
                    app.S.simple_thresholding();
                    app.seg_steps(next).type='simple_thresholding';
                    
                case 'Percentile'
                    
                    params.percentile(app.curr_t) = app.Percentile_value.Value;
                    
                    %update S, run task. 
                    app.S.params = params;
                    app.S.hist_thresholding();
                    app.seg_steps(next).type='hist_thresholding';
                    
                case 'Local (h_depth)'
                    
                    params.Hdepth = app.Localseg_hdepth_value.Value;
                    params.local_threshold_factor = app.dropfactorEditField.Value;
                    
                    %update S, run task. 
                    app.S.params = params;
                    app.S.adaptive_thresholding();
                    app.seg_steps(next).type = 'adaptive_thresholding';

            end

            app.store_seg_params(params);
            update_seg_steps(app)
            %app.view_layer='BW';
            %app.DispaylayerButtonGroup.SelectedObject=app.BWButton;
            app.update_image;
            % close the dialog box
            close(d);
        end

        % Button pushed function: ClosefilterButton
        function close_button(app, event)
            params = app.get_seg_params;
            params.imclose_r = app.imclose_radius_value.Value;
             d = uiprogressdlg(app.UIFigure,'Title','ImClose filter',...
                'Indeterminate','on');
        
            app.store_seg_params(params);
            % update S, run task. 
            app.S.params = params;
            app.S.close_filter();
            
            app.add_step('close_filter');
            app.update_image;
            close(d);
            
        end

        % Button pushed function: FilterOutliersButton
        function filter_outliers_button(app, event)
            params = app.get_seg_params;

            params.OutlierThreshold = app.outlier_threshold_value.Value;
            % Check dimensions. 
            switch app.img_dim
                
                case 2
                    params.MeanFilterNeighborhood= app.mean_filter_xy_value.Value;
                    
                case 3
                    params.MeanFilterNeighborhood= [app.mean_filter_xy_value.Value,app.mean_filter_xy_value.Value,app.mean_filter_z_value.Value];

            end           
            
            app.store_seg_params;
            % update S, run task. 
            app.S.params = params;
            app.S.filter_outliers();
            app.add_step('filter_outliers');
            app.update_image;

        end

        % Button pushed function: FillholesButton
        function fill_holes_button(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Filling holes',...
                'Indeterminate','on');
            app.S.fill_holes();
            app.add_step('fill_holes');
            app.update_image;
            close(d);

        end

        % Button pushed function: Watershed
        function watershed_button(app, event)
            params = app.get_seg_params;

            params.nconnBW = 18;
            params.h_min_depth=app.hmindepthEditField.Value;
            params.h_min_conn=6;
            params.z_effect = 2;
            params.WaterShedMaxVol = app.WaterShedMaxVol.Value;
            params.px_size = app.obj.exp_info.pixel_size;
            params.max_sphericity = app.max_sphericity.Value;
            
            d = uiprogressdlg(app.UIFigure,'Title','Computing watershed',...
                'Indeterminate','on');
        
            app.store_seg_params(params);

            % update S, run task. 
            app.S.params = params;
            app.S.watershed();
            app.add_step('watershed');
            app.update_image;
            close(d);
        end

        % Value changed function: MergerButton
        function merger_button(app, event)
            params = app.get_seg_params;
            params.merge_dist = app.mergedistEditField.Value;
            app.store_seg_params();
            % update s, run task. 
            app.S.params = params;
            app.S.merger();
            app.add_step('merge_objects');
            app.update_image;
        end

        % Button pushed function: SaveParametersButton
        function save_params_button(app, event)
            
            params = app.get_seg_params();

            % Steps depends on which tab we are on....
            switch app.seg_type
                
                
                case {'cells','foci'}   
                    
                    % Update obj params. 
                    app.obj.exp_info.params.(app.channel_str).(app.seg_type) = params;
                
                    app.obj.exp_info.steps.(app.channel_str).(app.seg_type) = app.seg_steps;
                    
                    % Check if there was a mask channel used. 
                    %if ischar(app.cell_mask_channel_str)
                    %    app.obj.exp_info.steps.(app.channel_str).mask_channel  = app.cell_mask_channel.Value;
                    %else
                    %    app.obj.exp_info.steps.(app.channel_str).mask_channel  = 0;
                    %end

                    % Interpolate percentiles/thresholds if multiple saved values.
                    if isfield(params,'percentile')
                        frames=find(params.percentile);
                        N=length(app.obj.exp_info.time_series);
                        
                        if length(frames) > 1
                            
                            pcs=interp1(frames,params.percentile(frames),1:N, 'pchip');
                            app.obj.exp_info.params.(app.channel_str).(app.seg_type).percentile=pcs;
                            
                            pcs=interp1(frames,params.simple_threshold(frames),1:N, 'pchip');
                            app.obj.exp_info.params.(app.channel_str).(app.seg_type).simple_threshold=pcs;
                                                    
                        else
                            app.obj.exp_info.params.(app.channel_str).(app.seg_type).percentile(1:N) = app.obj.exp_info.params.(app.channel_str).(app.seg_type).percentile;
                        end
                    end
                    
                    app.obj.exp_info.params.(app.channel_str).(app.seg_type).seg_dims=app.img_dim;
                    
                    if app.img_dim==2
                        app.obj.exp_info.params.(app.channel_str).(app.seg_type).z_range= app.z_range_low.Value:app.z_range_high.Value;
                    end
                    
                    % Also export parameters to dated file. 
                    FileName=[app.obj.exp_info.nuc_seg_dir,'params_',datestr(now,'HH_MM_SS_dd_mmm_yyyy'),'.mat'];
                    params = app.obj.exp_info.params.(app.channel_str).(app.seg_type);
                    steps = app.seg_steps;
                    save(FileName,'params','steps');
                    
                case 'spots'
                    
                    app.obj.exp_info.steps.(app.channel_str).spots = app.seg_steps;
                    app.obj.exp_info.params.(app.channel_str).spots = params;
                    if app.img_dim==2
                        app.obj.exp_info.params.(app.channel_str).spots.z_range= app.z_range_low.Value:app.z_range_high.Value;
                    end
                    app.obj.exp_info.params.(app.channel_str).spots.seg_dims=app.img_dim;
          
                    % Check if there was a mask channel used. 
                    if ischar(app.cell_mask_channel_str)
                        app.obj.exp_info.steps.(app.channel_str).mask_channel  = app.cell_mask_channel.Value;
                    else
                        app.obj.exp_info.steps.(app.channel_str).mask_channel  = 0;

                    end
                    % Also export parameters to dated file. 
                    FileName=[app.obj.exp_info.nuc_seg_dir,'spot_params_',datestr(now,'HH_MM_SS_dd_mmm_yyyy'),'.mat'];
                    spot_params = app.obj.exp_info.params.(app.channel_str).spots;
                    steps = app.seg_steps;
                    save(FileName,'spot_params','steps');
            end
            disp(['Saved parameters: ', FileName]);
            app.obj.save;
            
        end

        % Button pushed function: VolumehistogramButton
        function volume_hist_function(app, event)
            
            % Get stats. 
            vols = [app.S.stats.Volume];
            figure(12);
            hist(vols,50);
            std_figure_settings
            xlabel("Object volume (px's)")
            ylabel('Counts')

        end

        % Callback function
        function change_channel(app, event)
            app.channel=app.ChannelEditField.Value;
            app.update_stack;
            app.initialize_seg_steps
            app.initialize_seg_params(app.curr_t);
            
        end

        % Callback function
        function image_hist_button(app, event)
            
            %Diplay percentile histogram. 
            figure(13)
            pcs=linspace(0,100,200);
            vals=prctile(app.S.(app.view_layer)(:),pcs);
            std_figure_settings
            plot(pcs,vals)
        end

        % Value changed function: TimeindexEditField
        function frame_value_box(app, event)
            value = app.TimeindexEditField.Value;
            app.curr_t=min(length(app.obj.exp_info.time_series),round(value));
            app.TimeindexEditField.Value=app.curr_t;
            app.update_stack
            app.initialize_seg_steps;
        end

        % Button pushed function: BackButton
        function back_frame_button(app, event)
            app.curr_t=max(1,app.curr_t-1);
            app.TimeindexEditField.Value=app.curr_t;
            app.update_stack
            app.initialize_seg_steps;
        end

        % Button pushed function: ForwardButton
        function forward_frame_button(app, event)
            app.curr_t=min(length(app.obj.exp_info.time_series),app.curr_t+1);
            app.TimeindexEditField.Value=app.curr_t;
            app.update_stack;
            app.initialize_seg_steps;
        end

        % Value changed function: AbsMinVol
        function abs_min_change(app, event)
            value = app.AbsMinVol.Value;
            params = app.get_seg_params;
            params.AbsMinVol=value;
        end

        % Value changed function: AbsMaxVol
        function abs_max_change(app, event)
            value = app.AbsMaxVol.Value;
            app.seg_params.AbsMaxVol=value;
        end

        % Selection changed function: ButtonGroup
        function dimension_button_change(app, event)
            selectedButton = app.ButtonGroup.SelectedObject.Text;
            
            switch selectedButton
                
                case '2D'
                    
                    app.img_dim=2;
                                        
                case '3D'
                    
                    app.img_dim=3;
                                    
            end
            app.update_stack
        end

        % Button pushed function: FilterVolsButton
        function filter_vols_button(app, event)
            params = app.get_seg_params;

            params.AbsMinVol = app.AbsMinVol.Value;
            params.AbsMaxVol = app.AbsMaxVol.Value;
            app.store_seg_params(params);
                
            % update S , run task. 
            app.S.params = params;
            app.S.filter_objects();          
            app.update_image;
            app.add_step('filter_objects')
        end

        % Button pushed function: LoadnucleusmasksButton
        function load_cell_mask_button(app, event)


            % first check if mask already added? 
            if any( strcmp('add_mask',{app.seg_steps(:).type}))
                error("Already added a mask!")
            end

            % Load the current cell mask. 
            frame_files = app.obj.get_frame_files;
            load(frame_files{app.curr_t});
            app.frame_obj = frame_obj;
            
            % Get mask channel. 
            mask_channel = app.cell_mask_channel.Value;
            seg_channel_str = ['seg_channel_', pad(num2str(mask_channel), 2, 'left', '0')];
            % adjust mask if image size reduced. 
            %img_scale = app.ImageScaleEditField.Value;
            %if img_scale > 1
            %   px = cat(1,frame_obj.(seg_channel_str).cells.stats(:).PixelIdxList);
            %   BW=app.obj.rebuild_BW(px);
            %   newBW=imresize3(BW,1/img_scale);
            %   new_stats=regionprops(newBW,'Centroid','PixelIdxList');            % add stats to frame_obj. %

            %    mask = list_2_cell_array(new_stats,'PixelIdxList');
            %    app.S.add_mask(mask);
            %else
                %Update the segmenter with a mask. ***Need to convert mask
                %to cell array for use with segmenter. 
            mask = list_2_cell_array(app.frame_obj.(seg_channel_str).cells.stats,'PixelIdxList');
            app.S.add_mask(mask);
            %end
            
            app.set_cell_mask_channel();
            app.update_image
            app.add_step('add_mask',mask_channel)
            % print to screen
            disp("Loaded nuclei masks")
        end

        % Button pushed function: PresmoothButton
        function pre_smooth_button(app, event)
            % Smooth spot image. 
            params = app.get_seg_params;
            params.smooth_sigma=[app.spot_sigma_xy.Value,app.spot_sigma_xy.Value,app.spot_sigma_z.Value];
            
            app.store_seg_params(params);

            %update s, run task. 
            app.S.params = params;
            app.S.pre_smooth;
            app.add_step('pre_smooth')
            app.update_image;
            
        end

        % Button pushed function: LogfilterButton
        function log_filter_button(app, event)

            params = app.get_seg_params;

            % Get filter sigma. 
            params.spot_sigma = [app.spot_log_sigma_xy.Value, app.spot_log_sigma_xy.Value, app.spot_log_sigma_z.Value];
            
            % Check if subtract local background
            if app.SubtractlocalbackgroundCheckBox.Value
                app.S.subtract_local_background;
                app.add_step('subtract_local_background')
            end

            app.store_seg_params(params);

            app.S.params = params;
            app.S.log_filter;
            app.add_step('log_filter')
            app.view_layer = 'filtered';
            app.DispaylayerButtonGroup.SelectedObject=app.FilteredButton;
            layer_change(app, event)
        end

        % Button pushed function: LocalButton, SimpleButton_2
        function spot_threshold(app, event)
            selectedButton = event.Source.Text;

            % Check if last step was thresholding. 
            types={'local_spot_threshold','spot_simple_threshold'};
            % Check if any previous steps were thresholding.
            next =length(app.seg_steps)+1;
            for s = 1:length(app.seg_steps)
                if any( strcmp(types, app.seg_steps(s).type ))
                    next = s;
                    app.seg_steps = app.seg_steps(1:s-1);
                    break
                end
            end


            % get current parameters. 
            params = app.get_seg_params;
            disp(selectedButton)
            switch selectedButton
                
                
                case 'Simple'
                    
                    display('simple thresholding...')

                    params = app.get_seg_params();
                    % Get percentile values. 
                    params.simple_threshold = app.spot_simple_threshold.Value;
                    app.S.params = params;

                    app.S.spot_simple_threshold()
                    app.add_step('spot_simple_threshold');
                    
                case 'Local'
                    
                    params = app.get_seg_params();
                    % Get percentile values. 
                    params.local_thresh_percentile = [app.spot_percentile_img.Value, app.spot_percentile_filtered.Value];
                    

                    % need to check if cell masks are included. 
                    if isempty(app.S.msk_pxs)
                        message = {'No nucelei masks applied yet!'};
                        uialert(app.UIFigure,message,'Warning',...
                        'Icon','warning');
                    end
                    % update S params, run task. 
                    app.S.params = params;
                    app.S.local_spot_threshold();
                    app.add_step('local_spot_threshold');
                    
                    
            end

            app.store_seg_params(params)
            %app.view_layer='BW';
            %app.DispaylayerButtonGroup.SelectedObject=app.BWButton;
            app.update_image;
        end

        % Callback function
        function spot_mask_button(app, event)
            % Check if BW exists. 
            if isempty(app.cell_bw)
                error('No cell mask.')
            end

            % Mask out detected regions using cell mask. 
            if length(size(app.cell_bw))==2 & length(size(app.Filtered))==3
                BW = repmat(app.cell_bw,[1,1,size(app.Filtered,3)]);
            elseif length(size(app.cell_bw))==3 & length(size(app.Filtered))==3
                BW = app.cell_bw;
            else
                error('Incompatible img dims')
            end
            
            % Mask out regions. 
            app.BW = app.BW .* BW;
            app.spot_stats = regionprops(app.BW,'Centroid','PixelIdxList','Area');
            app.add_step('mask_spots');
            app.update_image
        end

        % Value changed function: cell_mask_channel
        function set_cell_mask_channel(app, event)
            value = app.cell_mask_channel.Value;
            %app.cell_mask_channel=value;
            app.cell_mask_channel_str = ['seg_channel_', pad(num2str(value), 2, 'left', '0')];
        end

        % Button pushed function: MergeduplicatesButton
        function merge_duplicates_button(app, event)
            
            app.seg_params.(app.channel_str).spots.merge_distance = app.merge_distance.Value;
            app.spot_stats = mege_duplicates( app.spot_stats, app.seg_params.(app.channel_str).spots.merge_distance);
            app.add_step('merge_duplicates')
        end

        % Button pushed function: FiltervolumeButton
        function filter_sizes_button(app, event)

            params = app.get_seg_params();
            params.AbsMinVol = app.spot_size_lb.Value;
            params.AbsMaxVol = app.spot_size_ub.Value;
            app.S.params = params;
            app.S.filter_objects();
            app.store_seg_params(params)

            app.update_image;
            app.add_step('filter_objects')
        end

        % Button pushed function: RefinemaskButton
        function refine_mask_button(app, event)
            params = app.get_seg_params();
            params.mask_erosion_size = app.mask_erosion_size.Value;
            app.store_seg_params(params);
            app.S.params = params;
            app.S.refine_mask();
            app.add_step('refine_mask');
            app.update_image;
        end

        % Callback function: SegmentTypeButtonGroup, SegmentTypeButtonGroup
        function seg_type_change(app, event)
            selectedButton = app.SegmentTypeButtonGroup.SelectedObject.Text;
            
            switch selectedButton
                case "nuclei"
                    app.seg_type='cells';
                case "foci"
                    app.seg_type='foci';
                case "spots"
                    app.seg_type='spots';
            end

            % re-initialize seg parameters. 
            app.initialize_seg_params(app.curr_t);
        end

        % Button pushed function: SubregionSegmentationButton
        function SubregionSegmentation(app, event)
            params = app.get_seg_params;

            % get the parameters. 
            params.snr_ratio= app.SNRthreshold.Value;
            params.clip_prctile=[app.ClipprctilerangeLower.Value,app.ClipprctilerangeUpper.Value];
            params.foci_size_filter=[app.FociSizeMin.Value,app.FociSizeMax.Value];
            params.nucleus_int_threshold = app.NucleusintensitythresholdEditField.Value;

            % Get layer choice. 
            params.use_layer = app.get_layer_choice();

            % first check that mask has been loaded. 
            if isempty(app.S.msk_pxs) % don't need centroids anymore??? || isempty(app.S.msk_centroids)
                message = {'No nucelei masks applied yet!'};
                        uialert(app.UIFigure,message,'Warning',...
                        'Icon','warning');
                return
            end
            app.store_seg_params(params);

            %Update s, run task. 
            app.S.params = params;
            app.S.sub_region_segmentation();

            % Switch to BW layer automatically?
            app.DispaylayerButtonGroup.SelectedObject=app.BWButton;
            %app.view_layer='BW';
            %app.layer_change;
            app.add_step('sub_region_segmentation')
            app.update_image();

        end

        % Selection change function: TabGroup
        function seg_tab_change(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            % need to run default parameters? 
        end

        % Callback function
        function image_reduction_field(app, event)
            value = app.ImageScaleEditField.Value;
            app.update_stack();
        end

        % Button pushed function: UpdatestackButton
        function update_stack_button(app, event)
            app.channel=app.ChannelEditField.Value;
            app.update_stack();
        end

        % Button pushed function: SphericityhistogramButton
        function sphericity_histogram(app, event)

            S = app.S.sphericity_calculation();
            newFigure(10)
            hist(S,50);
            std_figure_settings
            xlabel("Sphericity")
            ylabel('Counts')

        end

        % Button pushed function: SphericityfilterButton
        function sphericity_filter(app, event)
            S = app.S.sphericity_calculation();

            params = app.get_seg_params;
            params.min_sphericity = app.min_sphericity_box.Value;
            params.max_sphericity = app.max_sphericity_box.Value;
            app.store_seg_params(params);
            app.S.params = params;

            app.S.sphericity_filter;
            app.add_step('sphericity_filter')
            app.update_image();
        end

        % Button pushed function: sizehistogramButton
        function volume_histogram(app, event)
            V = [app.S.stats.Volume];
            newFigure(5)
            hist(V,50);
            std_figure_settings
            xlabel("Volume (px)")
            ylabel('Counts')

        end

        % Button pushed function: GaussianButton
        function gaussian_fit(app, event)
             % fit spots to 2D or 3D gaussian. TBD. 
        end

        % Button pushed function: ArbitraryblobButton
        function blob_fit(app, event)

            % Intensity analysis of arbitrary shaped blobs in 2D/3D. 
            params = app.get_seg_params;
            % Collect parameters and update. 
            params.fit_type = 'arbitary';
            params.bg_mask = [app.spot_bg_min.Value,app.spot_bg_max.Value];
            params.search_radius = app.spot_search_radius.Value;
            app.store_seg_params(params)
            app.S.params = params;

            d = uiprogressdlg(app.UIFigure,'Title','Fitting spots',...
            'Indeterminate','on');
    
            app.S.spot_fitting()
            app.add_step('spot_fitting');
            close(d)
        end

        % Button pushed function: PlotStatsButton
        function plot_fit_stats(app, event)
            % Make plots of the results of spot fitting procedure. 

            newFigure(3)

            subplot(3,2,1)
            x = [app.S.spot_fits.snr];
            
            hist(x,50)
            title('SNR')
            subplot(3,2,2)
            x = [app.S.spot_fits.mean_int];
            
            hist(x,50)
            title('Mean intensity above bg')
            subplot(3,2,3)
            x = [app.S.spot_fits.sum_int];
            
            hist(x,50)
            title('Sum raw intensity')
            
            subplot(3,2,4)
            x = [app.S.spot_fits.real_bg];
            hist(x,50)
            title('Mean background intensity')
            
            subplot(3,2,5)
            x = [app.S.spot_fits.size];
            hist(x,50)
            title('Spot volume')


        end

        % Button pushed function: SNRthresholdButton
        function snr_thresh_button(app, event)
            params = app.get_seg_params;
            params.spot_snr_threshold = app.snr_threshold.Value;
            
            app.store_seg_params(params)
            app.S.params = params;
            
            app.S.filter_spot_snr();
            app.add_step('filter_spot_snr');
            app.update_image();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @seg_tab_change, true);
            app.NucleifociTab = uitab(app.TabGroup);
            app.NucleifociTab.Title = 'Nuclei/foci';
            app.layer_choice_panel = uipanel(app.NucleifociTab);
            app.rawCheckBox = uicheckbox(app.layer_choice_panel);
            app.rawCheckBox.Text = 'use raw image';
            app.rawCheckBox.Value = true;
            app.filteredCheckBox = uicheckbox(app.layer_choice_panel);
            app.filteredCheckBox.Text = 'use filtered image';
            app.PreprocessrawimagePanel = uipanel(app.NucleifociTab);
            app.PreprocessrawimagePanel.Title = 'Pre-process raw image';
            app.MeanfilterButton = uibutton(app.PreprocessrawimagePanel, 'push');
            app.MeanfilterButton.ButtonPushedFcn = createCallbackFcn(app, @mean_filter_button, true);
            app.MeanfilterButton.Text = 'Mean filter';
            app.xyEditField_3Label = uilabel(app.PreprocessrawimagePanel);
            app.xyEditField_3Label.Text = 'xy';
            app.mean_filter_xy_value = uieditfield(app.PreprocessrawimagePanel, 'numeric');
            app.zLabel = uilabel(app.PreprocessrawimagePanel);
            app.zLabel.Text = 'z';
            app.mean_filter_z_value = uieditfield(app.PreprocessrawimagePanel, 'numeric');
            app.SensitivityEditFieldLabel = uilabel(app.PreprocessrawimagePanel);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity';
            app.SensitivityEditField = uieditfield(app.PreprocessrawimagePanel, 'numeric');
            app.SubBackChkbox = uicheckbox(app.PreprocessrawimagePanel);
            app.SubBackChkbox.Text = {''; ''};
            app.SubbackgroundButton = uibutton(app.PreprocessrawimagePanel, 'push');
            app.SubbackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @sub_background_button, true);
            app.SubbackgroundButton.Text = 'Sub background';
            app.background_value = uieditfield(app.PreprocessrawimagePanel, 'numeric');
            app.CLAHEButton = uibutton(app.PreprocessrawimagePanel, 'push');
            app.CLAHEButton.ButtonPushedFcn = createCallbackFcn(app, @clahe_button, true);
            app.CLAHEButton.Text = 'CLAHE';
            app.TileSizeEditFieldLabel = uilabel(app.PreprocessrawimagePanel);
            app.TileSizeEditFieldLabel.Text = 'Tile Size';
            app.TileSizeEditField = uieditfield(app.PreprocessrawimagePanel, 'numeric');
            app.ClipLimitEditFieldLabel = uilabel(app.PreprocessrawimagePanel);
            app.ClipLimitEditFieldLabel.Text = 'Clip Limit';
            app.ClipLimitEditField = uieditfield(app.PreprocessrawimagePanel, 'numeric');
            app.CLAHEChkbox = uicheckbox(app.PreprocessrawimagePanel);
            app.CLAHEChkbox.Text = {''; ''};
            app.PostprocessingPanel = uipanel(app.NucleifociTab);
            app.PostprocessingPanel.Title = 'Post-processing';
            app.imclose_radius_value = uieditfield(app.PostprocessingPanel, 'numeric');
            app.outlier_threshold_value = uieditfield(app.PostprocessingPanel, 'numeric');
            app.ClosefilterButton = uibutton(app.PostprocessingPanel, 'push');
            app.ClosefilterButton.ButtonPushedFcn = createCallbackFcn(app, @close_button, true);
            app.ClosefilterButton.Text = 'Close filter';
            app.FilterOutliersButton = uibutton(app.PostprocessingPanel, 'push');
            app.FilterOutliersButton.ButtonPushedFcn = createCallbackFcn(app, @filter_outliers_button, true);
            app.FilterOutliersButton.Text = 'Filter Outliers';
            app.pxLabel = uilabel(app.PostprocessingPanel);
            app.pxLabel.Text = 'px';
            app.pxLabel_2 = uilabel(app.PostprocessingPanel);
            app.pxLabel_2.Text = 'px';
            app.FillholesButton = uibutton(app.PostprocessingPanel, 'push');
            app.FillholesButton.ButtonPushedFcn = createCallbackFcn(app, @fill_holes_button, true);
            app.FillholesButton.Text = 'Fill holes';
            app.Watershed = uibutton(app.PostprocessingPanel, 'push');
            app.Watershed.ButtonPushedFcn = createCallbackFcn(app, @watershed_button, true);
            app.Watershed.Text = {'Watershed'; ''};
            app.hmindepthEditFieldLabel = uilabel(app.PostprocessingPanel);
            app.hmindepthEditFieldLabel.Text = 'hmin depth';
            app.hmindepthEditField = uieditfield(app.PostprocessingPanel, 'numeric');
            app.maxvolumeEditFieldLabel = uilabel(app.PostprocessingPanel);
            app.maxvolumeEditFieldLabel.Text = 'max volume';
            app.WaterShedMaxVol = uieditfield(app.PostprocessingPanel, 'numeric');
            app.VolumesEditFieldLabel = uilabel(app.PostprocessingPanel);
            app.VolumesEditFieldLabel.Text = 'Volumes';
            app.AbsMinVol = uieditfield(app.PostprocessingPanel, 'numeric');
            app.AbsMinVol.ValueChangedFcn = createCallbackFcn(app, @abs_min_change, true);
            app.AbsMaxVol = uieditfield(app.PostprocessingPanel, 'numeric');
            app.AbsMaxVol.ValueChangedFcn = createCallbackFcn(app, @abs_max_change, true);
            app.VolumehistogramButton = uibutton(app.PostprocessingPanel, 'push');
            app.VolumehistogramButton.ButtonPushedFcn = createCallbackFcn(app, @volume_hist_function, true);
            app.VolumehistogramButton.Text = 'Volume histogram';
            app.FilterVolsButton = uibutton(app.PostprocessingPanel, 'push');
            app.FilterVolsButton.ButtonPushedFcn = createCallbackFcn(app, @filter_vols_button, true);
            app.FilterVolsButton.Text = 'Filter Vols';
            app.pxLabel_3 = uilabel(app.PostprocessingPanel);
            app.pxLabel_3.Text = 'px';
            app.MergerButton = uibutton(app.PostprocessingPanel, 'state');
            app.MergerButton.ValueChangedFcn = createCallbackFcn(app, @merger_button, true);
            app.MergerButton.Text = 'Merger';
            app.mergedistEditFieldLabel = uilabel(app.PostprocessingPanel);
            app.mergedistEditFieldLabel.Text = 'merge dist';
            app.mergedistEditField = uieditfield(app.PostprocessingPanel, 'numeric');
            app.maxsphericityLabel = uilabel(app.PostprocessingPanel);
            app.maxsphericityLabel.Text = 'max sphericity';
            app.max_sphericity = uieditfield(app.PostprocessingPanel, 'numeric');
            app.max_sphericity.Value = 0.34;
            app.DiffusionGradientfilteringPanel = uipanel(app.NucleifociTab);
            app.DiffusionGradientfilteringPanel.Title = 'Diffusion Gradient filtering';
            app.gradientimageButton = uibutton(app.DiffusionGradientfilteringPanel, 'push');
            app.gradientimageButton.ButtonPushedFcn = createCallbackFcn(app, @gradient_button, true);
            app.gradientimageButton.Text = 'gradient image';
            app.AlphaEditFieldLabel = uilabel(app.DiffusionGradientfilteringPanel);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.DiffusionGradientfilteringPanel, 'numeric');
            app.BetaEditFieldLabel = uilabel(app.DiffusionGradientfilteringPanel);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.DiffusionGradientfilteringPanel, 'numeric');
            app.EpsilonEditFieldLabel = uilabel(app.DiffusionGradientfilteringPanel);
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.EpsilonEditField = uieditfield(app.DiffusionGradientfilteringPanel, 'numeric');
            app.DeltaEditFieldLabel = uilabel(app.DiffusionGradientfilteringPanel);
            app.DeltaEditFieldLabel.Text = 'Delta';
            app.DeltaEditField = uieditfield(app.DiffusionGradientfilteringPanel, 'numeric');
            app.GammaEditFieldLabel = uilabel(app.DiffusionGradientfilteringPanel);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.DiffusionGradientfilteringPanel, 'numeric');
            app.MedianFilterpxPanel = uipanel(app.DiffusionGradientfilteringPanel);
            app.MedianFilterpxPanel.Title = 'Median Filter (px)';
            app.xyEditFieldLabel = uilabel(app.MedianFilterpxPanel);
            app.xyEditFieldLabel.Text = 'xy';
            app.xyEditField = uieditfield(app.MedianFilterpxPanel, 'numeric');
            app.zEditFieldLabel = uilabel(app.MedianFilterpxPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.MedianFilterpxPanel, 'numeric');
            app.SigmaGradientFilterpxPanel = uipanel(app.DiffusionGradientfilteringPanel);
            app.SigmaGradientFilterpxPanel.Title = 'Sigma Gradient Filter (px)';
            app.xyEditField_2Label = uilabel(app.SigmaGradientFilterpxPanel);
            app.xyEditField_2Label.Text = 'xy';
            app.xyEditField_2 = uieditfield(app.SigmaGradientFilterpxPanel, 'numeric');
            app.zEditField_2Label = uilabel(app.SigmaGradientFilterpxPanel);
            app.zEditField_2Label.Text = 'z';
            app.zEditField_2 = uieditfield(app.SigmaGradientFilterpxPanel, 'numeric');
            app.ThresholdingButtonGroup = uibuttongroup(app.NucleifociTab);
            app.ThresholdingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @threshold_button, true);
            app.ThresholdingButtonGroup.Title = 'Thresholding';
            app.SimpleThresh_value = uieditfield(app.ThresholdingButtonGroup, 'numeric');
            app.Percentile_value = uieditfield(app.ThresholdingButtonGroup, 'numeric');
            app.Localseg_hdepth_value = uieditfield(app.ThresholdingButtonGroup, 'numeric');
            app.SimpleButton = uibutton(app.ThresholdingButtonGroup, 'push');
            app.SimpleButton.ButtonPushedFcn = createCallbackFcn(app, @threshold_button, true);
            app.SimpleButton.Text = 'Simple';
            app.PercentileButton = uibutton(app.ThresholdingButtonGroup, 'push');
            app.PercentileButton.ButtonPushedFcn = createCallbackFcn(app, @threshold_button, true);
            app.PercentileButton.Text = 'Percentile';
            app.Localseg_Button = uibutton(app.ThresholdingButtonGroup, 'push');
            app.Localseg_Button.ButtonPushedFcn = createCallbackFcn(app, @threshold_button, true);
            app.Localseg_Button.Text = 'Local (h_depth)';
            app.dropfactorEditFieldLabel = uilabel(app.ThresholdingButtonGroup);
            app.dropfactorEditFieldLabel.Text = 'drop factor';
            app.dropfactorEditField = uieditfield(app.ThresholdingButtonGroup, 'numeric');
            app.dropfactorEditField.Value = 0.5;
            app.intLabel = uilabel(app.ThresholdingButtonGroup);
            app.intLabel.Text = 'int';
            app.Label = uilabel(app.ThresholdingButtonGroup);
            app.Label.Text = '%';
            app.Label_2 = uilabel(app.ThresholdingButtonGroup);
            app.Label_2.Text = '0-1';
            app.intLabel_2 = uilabel(app.ThresholdingButtonGroup);
            app.intLabel_2.Text = 'int';
            app.SubcellularsegmentationfociPanel = uipanel(app.NucleifociTab);
            app.SubcellularsegmentationfociPanel.Title = 'Sub-cellular segmentation (foci)';
            app.SubregionSegmentationButton = uibutton(app.SubcellularsegmentationfociPanel, 'push');
            app.SubregionSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @SubregionSegmentation, true);
            app.SubregionSegmentationButton.Text = 'segmentation';
            app.SNRthresholdEditFieldLabel = uilabel(app.SubcellularsegmentationfociPanel);
            app.SNRthresholdEditFieldLabel.Text = 'SNR threshold';
            app.SNRthreshold = uieditfield(app.SubcellularsegmentationfociPanel, 'numeric');
            app.SNRthreshold.Value = 7;
            app.ClipprctilerangeEditFieldLabel = uilabel(app.SubcellularsegmentationfociPanel);
            app.ClipprctilerangeEditFieldLabel.Text = 'Clip prctile range';
            app.ClipprctilerangeLower = uieditfield(app.SubcellularsegmentationfociPanel, 'numeric');
            app.ClipprctilerangeLower.Value = 10;
            app.ClipprctilerangeUpper = uieditfield(app.SubcellularsegmentationfociPanel, 'numeric');
            app.ClipprctilerangeUpper.Value = 90;
            app.SizefilterpxLabel = uilabel(app.SubcellularsegmentationfociPanel);
            app.SizefilterpxLabel.Text = 'Size filter (px)';
            app.FociSizeMin = uieditfield(app.SubcellularsegmentationfociPanel, 'numeric');
            app.FociSizeMin.Value = 5;
            app.FociSizeMax = uieditfield(app.SubcellularsegmentationfociPanel, 'numeric');
            app.FociSizeMax.Value = 5000;
            app.NucleusintensitythresholdEditFieldLabel = uilabel(app.SubcellularsegmentationfociPanel);
            app.NucleusintensitythresholdEditFieldLabel.Text = 'Nucleus intensity threshold';
            app.NucleusintensitythresholdEditField = uieditfield(app.SubcellularsegmentationfociPanel, 'numeric');
            app.SpotsTab = uitab(app.TabGroup);
            app.SpotsTab.Title = 'Spots';
            app.PresmoothButton = uibutton(app.SpotsTab, 'push');
            app.PresmoothButton.ButtonPushedFcn = createCallbackFcn(app, @pre_smooth_button, true);
            app.PresmoothButton.Text = 'Pre-smooth';
            app.xyEditField_3Label_2 = uilabel(app.SpotsTab);
            app.xyEditField_3Label_2.Text = 'xy';
            app.spot_sigma_xy = uieditfield(app.SpotsTab, 'numeric');
            app.spot_sigma_xy.Value = 3;
            app.zLabel_2 = uilabel(app.SpotsTab);
            app.zLabel_2.Text = 'z';
            app.spot_sigma_z = uieditfield(app.SpotsTab, 'numeric');
            app.spot_sigma_z.Value = 1;
            app.LogfilterButton = uibutton(app.SpotsTab, 'push');
            app.LogfilterButton.ButtonPushedFcn = createCallbackFcn(app, @log_filter_button, true);
            app.LogfilterButton.Text = 'Log filter';
            app.SpotthresholdingButtonGroup = uibuttongroup(app.SpotsTab);
            app.SpotthresholdingButtonGroup.Title = 'Spot thresholding';
            app.spot_simple_threshold = uieditfield(app.SpotthresholdingButtonGroup, 'numeric');
            app.spot_percentile_img = uieditfield(app.SpotthresholdingButtonGroup, 'numeric');
            app.spot_percentile_filtered = uieditfield(app.SpotthresholdingButtonGroup, 'numeric');
            app.SimpleButton_2 = uibutton(app.SpotthresholdingButtonGroup, 'push');
            app.SimpleButton_2.ButtonPushedFcn = createCallbackFcn(app, @spot_threshold, true);
            app.SimpleButton_2.Text = 'Simple';
            app.LocalButton = uibutton(app.SpotthresholdingButtonGroup, 'push');
            app.LocalButton.ButtonPushedFcn = createCallbackFcn(app, @spot_threshold, true);
            app.LocalButton.Text = 'Local';
            app.rawLabel = uilabel(app.SpotthresholdingButtonGroup);
            app.rawLabel.Text = 'raw';
            app.filteredLabel = uilabel(app.SpotthresholdingButtonGroup);
            app.filteredLabel.Text = 'filtered';
            app.PercentilesLabel = uilabel(app.SpotthresholdingButtonGroup);
            app.PercentilesLabel.Text = 'Percentiles';
            app.MergeduplicatesButton = uibutton(app.SpotsTab, 'push');
            app.MergeduplicatesButton.ButtonPushedFcn = createCallbackFcn(app, @merge_duplicates_button, true);
            app.MergeduplicatesButton.Text = 'Merge duplicates';
            app.xyEditField_3Label_3 = uilabel(app.SpotsTab);
            app.xyEditField_3Label_3.Text = 'xy';
            app.spot_log_sigma_xy = uieditfield(app.SpotsTab, 'numeric');
            app.spot_log_sigma_xy.Value = 3;
            app.zLabel_3 = uilabel(app.SpotsTab);
            app.zLabel_3.Text = 'z';
            app.spot_log_sigma_z = uieditfield(app.SpotsTab, 'numeric');
            app.spot_log_sigma_z.Value = 1;
            app.SubtractlocalbackgroundCheckBox = uicheckbox(app.SpotsTab);
            app.SubtractlocalbackgroundCheckBox.Text = 'Subtract local background';
            app.distancepxEditFieldLabel = uilabel(app.SpotsTab);
            app.distancepxEditFieldLabel.Text = 'distance (px)';
            app.merge_distance = uieditfield(app.SpotsTab, 'numeric');
            app.merge_distance.Value = 4;
            app.SphericityfilterButton = uibutton(app.SpotsTab, 'push');
            app.SphericityfilterButton.ButtonPushedFcn = createCallbackFcn(app, @sphericity_filter, true);
            app.SphericityfilterButton.Text = 'Sphericity filter';
            app.SphericityhistogramButton = uibutton(app.SpotsTab, 'push');
            app.SphericityhistogramButton.ButtonPushedFcn = createCallbackFcn(app, @sphericity_histogram, true);
            app.SphericityhistogramButton.Text = 'Sphericity histogram';
            app.min_sphericity_box = uieditfield(app.SpotsTab, 'numeric');
            app.min_sphericity_box.Value = 0.6;
            app.max_sphericity_box = uieditfield(app.SpotsTab, 'numeric');
            app.max_sphericity_box.Value = 2;
            app.SpotfittingButtonGroup = uibuttongroup(app.SpotsTab);
            app.SpotfittingButtonGroup.Title = 'Spot fitting';
            app.SNRthresholdButton = uibutton(app.SpotfittingButtonGroup, 'push');
            app.SNRthresholdButton.ButtonPushedFcn = createCallbackFcn(app, @snr_thresh_button, true);
            app.SNRthresholdButton.Text = 'SNR threshold';
            app.snr_threshold = uieditfield(app.SpotfittingButtonGroup, 'numeric');
            app.snr_threshold.Value = 8;
            app.GaussianButton = uibutton(app.SpotfittingButtonGroup, 'push');
            app.GaussianButton.ButtonPushedFcn = createCallbackFcn(app, @gaussian_fit, true);
            app.GaussianButton.Text = 'Gaussian';
            app.ArbitraryblobButton = uibutton(app.SpotfittingButtonGroup, 'push');
            app.ArbitraryblobButton.ButtonPushedFcn = createCallbackFcn(app, @blob_fit, true);
            app.ArbitraryblobButton.Text = 'Arbitrary blob';
            app.spot_bg_min = uieditfield(app.SpotfittingButtonGroup, 'numeric');
            app.spot_bg_min.Value = 3;
            app.backgroundLabel = uilabel(app.SpotfittingButtonGroup);
            app.backgroundLabel.Text = 'background';
            app.spot_bg_max = uieditfield(app.SpotfittingButtonGroup, 'numeric');
            app.spot_bg_max.Value = 5;
            app.searchradiusLabel = uilabel(app.SpotfittingButtonGroup);
            app.searchradiusLabel.Text = 'search radius';
            app.spot_search_radius = uieditfield(app.SpotfittingButtonGroup, 'numeric');
            app.spot_search_radius.Value = 10;
            app.SpotsizefilteringButtonGroup = uibuttongroup(app.SpotsTab);
            app.SpotsizefilteringButtonGroup.Title = 'Spot size filtering';
            app.sizehistogramButton = uibutton(app.SpotsizefilteringButtonGroup, 'push');
            app.sizehistogramButton.ButtonPushedFcn = createCallbackFcn(app, @volume_histogram, true);
            app.sizehistogramButton.Text = 'size histogram';
            app.FiltervolumeButton = uibutton(app.SpotsizefilteringButtonGroup, 'push');
            app.FiltervolumeButton.ButtonPushedFcn = createCallbackFcn(app, @filter_sizes_button, true);
            app.FiltervolumeButton.Text = 'Filter volume';
            app.spot_size_lb = uieditfield(app.SpotsizefilteringButtonGroup, 'numeric');
            app.minLabel = uilabel(app.SpotsizefilteringButtonGroup);
            app.minLabel.Text = 'min';
            app.spot_size_ub = uieditfield(app.SpotsizefilteringButtonGroup, 'numeric');
            app.maxLabel = uilabel(app.SpotsizefilteringButtonGroup);
            app.maxLabel.Text = 'max ';
            app.PlotStatsButton = uibutton(app.SpotsTab, 'push');
            app.PlotStatsButton.ButtonPushedFcn = createCallbackFcn(app, @plot_fit_stats, true);
            app.PlotStatsButton.Text = 'Plot Stats';
            app.TrackCellsTab = uitab(app.TabGroup);
            app.TrackCellsTab.Title = 'Track Cells';
            app.DispaylayerButtonGroup = uibuttongroup(app.UIFigure);
            app.DispaylayerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @layer_change, true);
            app.DispaylayerButtonGroup.Title = 'Dispay layer';
            app.DispaylayerButtonGroup.ButtonDownFcn = createCallbackFcn(app, @layer_change, true);
            app.ImageButton = uitogglebutton(app.DispaylayerButtonGroup);
            app.ImageButton.Text = 'Image';
            app.ImageButton.Value = true;
            app.FilteredButton = uitogglebutton(app.DispaylayerButtonGroup);
            app.FilteredButton.Text = 'Filtered';
            app.BWButton = uitogglebutton(app.DispaylayerButtonGroup);
            app.BWButton.Text = 'BW';
            app.SaveParametersButton = uibutton(app.UIFigure, 'push');
            app.SaveParametersButton.ButtonPushedFcn = createCallbackFcn(app, @save_params_button, true);
            app.SaveParametersButton.Text = 'Save Parameters';
            app.CurrentStepsListBoxLabel = uilabel(app.UIFigure);
            app.CurrentStepsListBoxLabel.Text = 'Current Steps';
            app.CurrentStepsListBox = uilistbox(app.UIFigure);
            app.CurrentStepsListBox.Items = {};
            app.CurrentStepsListBox.Value = {};
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @back_frame_button, true);
            app.BackButton.Text = 'Back';
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @forward_frame_button, true);
            app.ForwardButton.Text = 'Forward';
            app.TimeindexEditFieldLabel = uilabel(app.UIFigure);
            app.TimeindexEditFieldLabel.Text = 'Time  index';
            app.TimeindexEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeindexEditField.ValueChangedFcn = createCallbackFcn(app, @frame_value_box, true);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @dimension_button_change, true);
            app.DButton = uitogglebutton(app.ButtonGroup);
            app.DButton.Text = '2D';
            app.DButton.Value = true;
            app.DButton_2 = uitogglebutton(app.ButtonGroup);
            app.DButton_2.Text = '3D';
            app.z_range_low = uieditfield(app.UIFigure, 'numeric');
            app.z_range_low.Value = 1;
            app.z_range_high = uieditfield(app.UIFigure, 'numeric');
            app.z_range_high.Value = 10;
            app.RESETsegButton = uibutton(app.UIFigure, 'push');
            app.RESETsegButton.ButtonPushedFcn = createCallbackFcn(app, @reset_button, true);
            app.RESETsegButton.Text = 'RESET seg';
            app.ChannelEditFieldLabel = uilabel(app.UIFigure);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.ChannelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChannelEditField.Limits = [1 10];
            app.ChannelEditField.RoundFractionalValues = 'on';
            app.ChannelEditField.Value = 1;
            app.subsampleCheckBox = uicheckbox(app.UIFigure);
            app.subsampleCheckBox.Text = 'sub-sample';
            app.SegmentTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.SegmentTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @seg_type_change, true);
            app.SegmentTypeButtonGroup.Title = 'Segment Type';
            app.SegmentTypeButtonGroup.ButtonDownFcn = createCallbackFcn(app, @seg_type_change, true);
            app.nucleiButton = uitogglebutton(app.SegmentTypeButtonGroup);
            app.nucleiButton.Text = 'nuclei';
            app.nucleiButton.Value = true;
            app.fociButton = uitogglebutton(app.SegmentTypeButtonGroup);
            app.fociButton.Text = 'foci';
            app.spotsButton = uitogglebutton(app.SegmentTypeButtonGroup);
            app.spotsButton.Text = 'spots';
            app.maskchannelLabel = uilabel(app.UIFigure);
            app.maskchannelLabel.Text = 'mask channel';
            app.cell_mask_channel = uieditfield(app.UIFigure, 'numeric');
            app.cell_mask_channel.ValueChangedFcn = createCallbackFcn(app, @set_cell_mask_channel, true);
            app.cell_mask_channel.Value = 1;
            app.LoadnucleusmasksButton = uibutton(app.UIFigure, 'push');
            app.LoadnucleusmasksButton.ButtonPushedFcn = createCallbackFcn(app, @load_cell_mask_button, true);
            app.LoadnucleusmasksButton.Text = {'Load nucleus'; 'masks'};
            app.ZrangeLabel = uilabel(app.UIFigure);
            app.ZrangeLabel.Text = 'Z-range';
            app.ImagereductionXfoldEditFieldLabel = uilabel(app.UIFigure);
            app.ImagereductionXfoldEditFieldLabel.Text = 'Image reduction (X-fold)';
            app.ImageScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageScaleEditField.Limits = [1 10];
            app.ImageScaleEditField.RoundFractionalValues = 'on';
            app.ImageScaleEditField.Value = 1;
            app.UpdatestackButton = uibutton(app.UIFigure, 'push');
            app.UpdatestackButton.ButtonPushedFcn = createCallbackFcn(app, @update_stack_button, true);
            app.UpdatestackButton.Text = 'Update stack';
            app.RefinemaskButton = uibutton(app.UIFigure, 'push');
            app.RefinemaskButton.ButtonPushedFcn = createCallbackFcn(app, @refine_mask_button, true);
            app.RefinemaskButton.Text = 'Refine mask';
            app.erodesizeLabel = uilabel(app.UIFigure);
            app.erodesizeLabel.Text = 'erode  size';
            app.mask_erosion_size = uieditfield(app.UIFigure, 'numeric');
            app.mask_erosion_size.Value = 4;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end