classdef params < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        SegmentCellsTab          matlab.ui.container.Tab
        ChannelEditFieldLabel    matlab.ui.control.Label
        ChannelEditField         matlab.ui.control.NumericEditField
        Button3                  matlab.ui.control.StateButton
        Button3_2                matlab.ui.control.StateButton
        Button3_3                matlab.ui.control.StateButton
        ThresholdingButtonGroup  matlab.ui.container.ButtonGroup
        SimpleButton             matlab.ui.control.ToggleButton
        PercentileButton         matlab.ui.control.ToggleButton
        LocalButton              matlab.ui.control.ToggleButton
        EditField_3              matlab.ui.control.NumericEditField
        EditField_4              matlab.ui.control.NumericEditField
        EditField_5              matlab.ui.control.NumericEditField
        EditField                matlab.ui.control.NumericEditField
        VolumesEditFieldLabel    matlab.ui.control.Label
        VolumesEditField         matlab.ui.control.NumericEditField
        background_value         matlab.ui.control.NumericEditField
        TileSizeEditFieldLabel   matlab.ui.control.Label
        TileSizeEditField        matlab.ui.control.NumericEditField
        ClipLimitEditFieldLabel  matlab.ui.control.Label
        ClipLimitEditField       matlab.ui.control.NumericEditField
        AlphaEditFieldLabel      matlab.ui.control.Label
        AlphaEditField           matlab.ui.control.NumericEditField
        BetaEditFieldLabel       matlab.ui.control.Label
        BetaEditField            matlab.ui.control.NumericEditField
        EpsilonEditFieldLabel    matlab.ui.control.Label
        EpsilonEditField         matlab.ui.control.NumericEditField
        SubBackChkbox            matlab.ui.control.CheckBox
        CLAHEChkbox              matlab.ui.control.CheckBox
        CheckBox_3               matlab.ui.control.CheckBox
        DeltaEditFieldLabel      matlab.ui.control.Label
        DeltaEditField           matlab.ui.control.NumericEditField
        SubbackgroundButton      matlab.ui.control.Button
        claheButton              matlab.ui.control.Button
        gradientimageButton      matlab.ui.control.Button
        GammaEditFieldLabel      matlab.ui.control.Label
        GammaEditField           matlab.ui.control.NumericEditField
        SegmentSpotsTab          matlab.ui.container.Tab
        TrackCellsTab            matlab.ui.container.Tab
    end

    
    properties (Access = private)
        CallingApp % Description
        seg_params
        seg_steps_done
        seg_steps_to_do
    end
    
    methods (Access = public)
        
        % Define the segmentation steps. 
        function out_steps = seg_steps(app)
            steps={'subtract_background',
            'clahe',
            'diff_gradients',
            'thresholding',
            'filter_objects',
            'close_filter',
            'filter_outliers',
            'fill_holes',
            'filter_objects',
            'watershed',
            'merge',
            'filter_rg'};
        
            out_steps(length(steps)) = struct('type',[],'value',[]);
            
            % Fill in steps. 
            for i = 1:length(out_steps)
            
                out_steps(i).type=steps{i};
                out_steps(i).value=0;
            
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
            
            % Initialize some important variables...
            app.seg_steps_to_do=seg_steps(app);
            app.seg_steps_done=seg_steps(app);
            
        end

        % Button pushed function: SubbackgroundButton
        function sub_background(app, event)
            % Subtrack background. 
            app.seg_params.bg = app.background_value;
            app.CallingApp.stack = subtract_background(app.CallingApp.stack,app.seg_params.bg);
            app.CallingApp.update_image;
            
            % Set checkbox 
            app.SubBackChkbox.Value=1;
        end

        % Button pushed function: claheButton
        function clahe_button(app, event)
            
            app.seg_params.tile_size=app.TileSizeEditField;
            app.seg_params.ClipLimit=app.ClipLimitEditField;
            app.CallingApp.stack = clahe(app.CallingApp.stack,app.seg_params.tile_size,app.seg_params.ClipLimit);
            
        end

        % Button pushed function: gradientimageButton
        function gradient_img(app, event)
            % Get parameters. 
            app.seg_params.alpha=app.AlphaEditField;
            app.seg_params.beta=app.BetaEditField;
            %app.seg_params.gamma=app.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SegmentCellsTab = uitab(app.TabGroup);
            app.SegmentCellsTab.Title = 'Segment Cells';
            app.ChannelEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.ChannelEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.ChannelEditField.Limits = [1 10];
            app.ChannelEditField.RoundFractionalValues = 'on';
            app.ChannelEditField.Value = 1;
            app.Button3 = uibutton(app.SegmentCellsTab, 'state');
            app.Button3.Text = 'Button3';
            app.Button3_2 = uibutton(app.SegmentCellsTab, 'state');
            app.Button3_2.Text = 'Button3';
            app.Button3_3 = uibutton(app.SegmentCellsTab, 'state');
            app.Button3_3.Text = 'Button3';
            app.ThresholdingButtonGroup = uibuttongroup(app.SegmentCellsTab);
            app.ThresholdingButtonGroup.Title = 'Thresholding';
            app.SimpleButton = uitogglebutton(app.ThresholdingButtonGroup);
            app.SimpleButton.Text = 'Simple';
            app.SimpleButton.Value = true;
            app.PercentileButton = uitogglebutton(app.ThresholdingButtonGroup);
            app.PercentileButton.Text = 'Percentile';
            app.LocalButton = uitogglebutton(app.ThresholdingButtonGroup);
            app.LocalButton.Text = 'Local';
            app.EditField_3 = uieditfield(app.ThresholdingButtonGroup, 'numeric');
            app.EditField_4 = uieditfield(app.ThresholdingButtonGroup, 'numeric');
            app.EditField_5 = uieditfield(app.ThresholdingButtonGroup, 'numeric');
            app.EditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.VolumesEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.VolumesEditFieldLabel.Text = 'Volumes';
            app.VolumesEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.background_value = uieditfield(app.SegmentCellsTab, 'numeric');
            app.TileSizeEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.TileSizeEditFieldLabel.Text = 'Tile Size';
            app.TileSizeEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.ClipLimitEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.ClipLimitEditFieldLabel.Text = 'Clip Limit';
            app.ClipLimitEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.AlphaEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.BetaEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.EpsilonEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.EpsilonEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.SubBackChkbox = uicheckbox(app.SegmentCellsTab);
            app.SubBackChkbox.Text = {''; ''};
            app.CLAHEChkbox = uicheckbox(app.SegmentCellsTab);
            app.CLAHEChkbox.Text = {''; ''};
            app.CheckBox_3 = uicheckbox(app.SegmentCellsTab);
            app.CheckBox_3.Text = {''; ''};
            app.DeltaEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.DeltaEditFieldLabel.Text = 'Delta';
            app.DeltaEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.SubbackgroundButton = uibutton(app.SegmentCellsTab, 'push');
            app.SubbackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @sub_background, true);
            app.SubbackgroundButton.Text = 'Sub background';
            app.claheButton = uibutton(app.SegmentCellsTab, 'push');
            app.claheButton.ButtonPushedFcn = createCallbackFcn(app, @clahe_button, true);
            app.claheButton.Text = 'clahe';
            app.gradientimageButton = uibutton(app.SegmentCellsTab, 'push');
            app.gradientimageButton.ButtonPushedFcn = createCallbackFcn(app, @gradient_img, true);
            app.gradientimageButton.Text = 'gradient image';
            app.GammaEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.SegmentSpotsTab = uitab(app.TabGroup);
            app.SegmentSpotsTab.Title = 'Segment Spots';
            app.TrackCellsTab = uitab(app.TabGroup);
            app.TrackCellsTab.Title = 'Track Cells';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = params(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end