classdef tool_box < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        SegmentCellsTab            matlab.ui.container.Tab
        ChannelEditFieldLabel      matlab.ui.control.Label
        ChannelEditField           matlab.ui.control.NumericEditField
        ClosefilterButton          matlab.ui.control.StateButton
        FilterOutliersButton       matlab.ui.control.StateButton
        FillholesButton            matlab.ui.control.StateButton
        ThresholdingButtonGroup    matlab.ui.container.ButtonGroup
        SimpleThresh_value         matlab.ui.control.NumericEditField
        Percentile_value           matlab.ui.control.NumericEditField
        LocalPercentile_value      matlab.ui.control.NumericEditField
        SimpleButton               matlab.ui.control.Button
        PercentileButton           matlab.ui.control.Button
        LocalButton                matlab.ui.control.Button
        AbsMaxVol                  matlab.ui.control.NumericEditField
        VolumesEditFieldLabel      matlab.ui.control.Label
        AbsMinVol                  matlab.ui.control.NumericEditField
        background_value           matlab.ui.control.NumericEditField
        TileSizeEditFieldLabel     matlab.ui.control.Label
        TileSizeEditField          matlab.ui.control.NumericEditField
        ClipLimitEditFieldLabel    matlab.ui.control.Label
        ClipLimitEditField         matlab.ui.control.NumericEditField
        AlphaEditFieldLabel        matlab.ui.control.Label
        AlphaEditField             matlab.ui.control.NumericEditField
        BetaEditFieldLabel         matlab.ui.control.Label
        BetaEditField              matlab.ui.control.NumericEditField
        EpsilonEditFieldLabel      matlab.ui.control.Label
        EpsilonEditField           matlab.ui.control.NumericEditField
        SubBackChkbox              matlab.ui.control.CheckBox
        CLAHEChkbox                matlab.ui.control.CheckBox
        CheckBox_3                 matlab.ui.control.CheckBox
        DeltaEditFieldLabel        matlab.ui.control.Label
        DeltaEditField             matlab.ui.control.NumericEditField
        SubbackgroundButton        matlab.ui.control.Button
        claheButton                matlab.ui.control.Button
        gradientimageButton        matlab.ui.control.Button
        GammaEditFieldLabel        matlab.ui.control.Label
        GammaEditField             matlab.ui.control.NumericEditField
        MedianFilterPanel          matlab.ui.container.Panel
        xyEditFieldLabel           matlab.ui.control.Label
        xyEditField                matlab.ui.control.NumericEditField
        zEditFieldLabel            matlab.ui.control.Label
        zEditField                 matlab.ui.control.NumericEditField
        SigmaGradientPanel         matlab.ui.container.Panel
        xyEditField_2Label         matlab.ui.control.Label
        xyEditField_2              matlab.ui.control.NumericEditField
        zEditField_2Label          matlab.ui.control.Label
        zEditField_2               matlab.ui.control.NumericEditField
        imclose_radius_value       matlab.ui.control.NumericEditField
        MeanfilterButton           matlab.ui.control.Button
        SensitivityEditFieldLabel  matlab.ui.control.Label
        SensitivityEditField       matlab.ui.control.NumericEditField
        RESETButton                matlab.ui.control.Button
        outlier_threshold_value    matlab.ui.control.NumericEditField
        WatershedButton            matlab.ui.control.StateButton
        hmindepthEditFieldLabel    matlab.ui.control.Label
        hmindepthEditField         matlab.ui.control.NumericEditField
        MergerButton               matlab.ui.control.StateButton
        mergedistEditFieldLabel    matlab.ui.control.Label
        mergedistEditField         matlab.ui.control.NumericEditField
        xyEditField_3Label         matlab.ui.control.Label
        mean_filter_xy_value       matlab.ui.control.NumericEditField
        zLabel                     matlab.ui.control.Label
        mean_filter_z_value        matlab.ui.control.NumericEditField
        SegmentSpotsTab            matlab.ui.container.Tab
        TrackCellsTab              matlab.ui.container.Tab
    end

    
    properties (Access = private)
        CallingApp % Description
        seg_params=struct();
        seg_steps
    end
    
    methods (Access = public)
        
        % Structure that defines the segmentation steps. Uses this to
        % record what the user does. 
        function out_steps = initialize_seg_steps(app)
%             steps={'subtract_background',
%             'clahe',
%             'diff_gradients',
%             'thresholding',
%             'filter_objects',
%             'close_filter',
%             'filter_outliers',
%             'fill_holes',
%             'filter_objects',
%             'watershed',
%             'merge',
%             'filter_rg'};
        
            out_steps = struct('type',[],'value',[]);
            
%             % Fill in steps. 
%             for i = 1:length(out_steps)
%             
%                 out_steps(i).type=steps{i};
%                 out_steps(i).value=0;
%             
%             end
        end
        
    end
    
    methods (Access = private)
        
        function add_step(app, this_step)
            
            %Check if previous step is same. If not, then append. 
            previous_step = app.seg_steps(end).type;
            
            if strcmp(previous_step, this_step)
                app.seg_steps(end).type = this_step;
            else
                app.seg_steps(end+1).type = this_step;
            end
        end
        
        function initialize_seg_params(app)
            
            app.seg_params.(app.CallingApp.channel_str).cells=[];
            if isfield(app.CallingApp.obj.exp_info,'params')
                if isfield(app.CallingApp.obj.exp_info.params,'cells')
                    %Pre-load parameters. 
                    app.seg_params.(app.CallingApp.channel_str).cells=app.CallingApp.obj.exp_info.params.cells;
                end
            end

            if isempty(app.seg_params.(app.CallingApp.channel_str).cells)
                app.seg_params.(app.CallingApp.channel_str).cells=app.default_cell_params;
            end
            
            % Set gui fields. 
            app.background_value.Value = app.seg_params.(app.CallingApp.channel_str).cells.bg;
            
            app.TileSizeEditField.Value = app.seg_params.(app.CallingApp.channel_str).cells.tile_size;
            app.ClipLimitEditField.Value = app.seg_params.(app.CallingApp.channel_str).cells.ClipLimit;
            
            app.AlphaEditField.Value = app.seg_params.(app.CallingApp.channel_str).cells.alpha;
            app.BetaEditField.Value= app.seg_params.(app.CallingApp.channel_str).cells.beta;
            app.GammaEditField.Value= app.seg_params.(app.CallingApp.channel_str).cells.gamma;
            app.DeltaEditField.Value=app.seg_params.(app.CallingApp.channel_str).cells.delta;
            app.EpsilonEditField.Value= app.seg_params.(app.CallingApp.channel_str).cells.epsilon;
            app.xyEditField_2.Value = app.seg_params.(app.CallingApp.channel_str).cells.sigmagradient(1);
            app.zEditField_2.Value =  app.seg_params.(app.CallingApp.channel_str).cells.sigmagradient(3);
            
            app.xyEditField.Value= app.seg_params.(app.CallingApp.channel_str).cells.median_filter(1);
            app.zEditField.Value = app.seg_params.(app.CallingApp.channel_str).cells.median_filter(3);

            app.SimpleThresh_value.Value=app.seg_params.(app.CallingApp.channel_str).cells.simple_threshold;
            app.Percentile_value.Value= app.seg_params.(app.CallingApp.channel_str).cells.percentile;
            app.LocalPercentile_value.Value=app.seg_params.(app.CallingApp.channel_str).cells.percentile;
            % Now tack on a filtering step. 
            app.AbsMinVol.Value= app.seg_params.(app.CallingApp.channel_str).cells.AbsMinVol;
            app.AbsMaxVol.Value=app.seg_params.(app.CallingApp.channel_str).cells.AbsMaxVol;
            app.imclose_radius_value.Value=app.seg_params.(app.CallingApp.channel_str).cells.imclose_r;
            app.SensitivityEditField.Value=app.seg_params.(app.CallingApp.channel_str).cells.MeanFilterSensitivity;
            app.mean_filter_xy_value.Value=app.seg_params.(app.CallingApp.channel_str).cells.MeanFilterNeighborhood(1);
            app.mean_filter_z_value.Value =app.seg_params.(app.CallingApp.channel_str).cells.MeanFilterNeighborhood(3); 

            app.outlier_threshold_value.Value=app.seg_params.(app.CallingApp.channel_str).cells.OutlierThreshold;
            app.hmindepthEditField.Value=app.seg_params.(app.CallingApp.channel_str).cells.h_min_depth;
            app.mergedistEditField.Value=app.seg_params.(app.CallingApp.channel_str).cells.merge_dist;

        end
        
        function out_params = default_cell_params(app)
            % Set gui fields. 
            out_params.bg=0;
            
            out_params.tile_size=25;
            out_params.ClipLimit=0.05;
            
            out_params.alpha=0;
            out_params.beta=0.1;
            out_params.gamma=8;
            out_params.delta=1;
            out_params.epsilon=0;
            out_params.sigmagradient=[5,5,2];
            
            out_params.median_filter = [7,7,1];
            
            out_params.simple_threshold = 100;
            out_params.percentile= 78;
            out_params.percentile= 78;
            
            % Now tack on a filtering step. 
            out_params.AbsMinVol=600;
            out_params.AbsMaxVol=3000;
            out_params.imclose_r=5;
            out_params.MeanFilterSensitivity=0.4;
            out_params.MeanFilterNeighborhood=[7,7,8];
            out_params.OutlierThreshold=60000;
            out_params.h_min_depth=1;
            out_params.merge_dist=20;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, obj)
            
            % Update the image and histograms
            img_file_name = obj.exp_info.img_file;
            
            % initialize time and channels. 
            app.curr_t=1;
            app.channel=1;
            app.curr_z=1;
            app.obj=obj;
            % Add image reader to app
            app.reader = bfGetReader(img_file_name);
            app.image_bits=app.reader.getBitsPerPixel;   
            
            app.view_layer='Image';
            %Initialize image
            update_stack(app);
            app.img_dim = length(size(app.Image));
            
            app.CallingApp=input_app;
            
            % Initialize some important variables...
            app.seg_steps=app.initialize_seg_steps();
            
            % Initialize segmentation parameters. Check if supplied by
            % calling app? 
            app.initialize_seg_params
        end

        % Button pushed function: SubbackgroundButton
        function sub_background(app, event)
            % Subtract background. 
            app.seg_params.(app.CallingApp.channel_str).cells.bg = app.background_value.Value;
            app.CallingApp.Image = subtract_background(app.CallingApp.Image,app.seg_params.(app.CallingApp.channel_str).cells.bg);
            app.CallingApp.update_image();
            
            % Record step
            app.add_step('sub_background');
            app.seg_steps(end+1).type='sub_background';
        end

        % Button pushed function: claheButton
        function clahe_button(app, event)
            
            app.seg_params.(app.CallingApp.channel_str).cells.tile_size=app.TileSizeEditField.Value;
            app.seg_params.(app.CallingApp.channel_str).cells.ClipLimit=app.ClipLimitEditField.Value;
            app.CallingApp.Image = clahe(app.CallingApp.Image,app.seg_params.(app.CallingApp.channel_str).cells.tile_size,app.seg_params.(app.CallingApp.channel_str).cells.ClipLimit);
            % Record step
            app.seg_steps(end+1).type='clahe';
        end

        % Button pushed function: gradientimageButton
        function gradient_img(app, event)
            
            % Fix kappa?
            app.seg_params.(app.CallingApp.channel_str).cells.kappa1=10;
            app.seg_params.(app.CallingApp.channel_str).cells.kappa2=0;
            app.seg_params.(app.CallingApp.channel_str).cells.option=1;
            app.seg_params.(app.CallingApp.channel_str).cells.diffuse_iterations=5;
            % Get parameters. 
            app.seg_params.(app.CallingApp.channel_str).cells.alpha=app.AlphaEditField.Value;
            app.seg_params.(app.CallingApp.channel_str).cells.beta=app.BetaEditField.Value;
            app.seg_params.(app.CallingApp.channel_str).cells.gamma=app.GammaEditField.Value;
            app.seg_params.(app.CallingApp.channel_str).cells.delta=app.DeltaEditField.Value;
            app.seg_params.(app.CallingApp.channel_str).cells.epsilon=app.EpsilonEditField.Value;
            app.seg_params.(app.CallingApp.channel_str).cells.sigmagradient = [app.xyEditField_2.Value,app.xyEditField_2.Value];
            app.seg_params.(app.CallingApp.channel_str).cells.median_filter = [app.xyEditField.Value,app.xyEditField.Value,app.zEditField.Value];
            
            app.CallingApp.Filtered = diff_gradients(app.CallingApp.Image, app.seg_params.(app.CallingApp.channel_str).cells);
            
            % Record step. 
            app.seg_steps(end+1).type='gradient_img';
            
        end

        % Callback function: LocalButton, PercentileButton, 
        % SimpleButton, ThresholdingButtonGroup
        function thresholding_fn(app, event)
            % Perform segmentation depending on button. 
            selectedButton = event.Source.Text;
            
            % Check if last step was thresholding. 
            types={'Simple','Percentile','Local'};
            if any( strcmp(types, app.seg_steps(end).type ))
                next = length(app.seg_steps);
            else
                next = length(app.seg_steps) + 1;
            end
            
            switch selectedButton
                
                case 'Simple'
                    
                    app.seg_params.(app.CallingApp.channel_str).cells.simple_threshold = app.SimpleThresh_value.Value;
                    app.CallingApp.BW = simple_thresholding(app.CallingApp.Filtered, app.seg_params.(app.CallingApp.channel_str).cells.simple_threshold );
                    app.seg_steps(next).type='simple_thresholding';
                    
                case 'Percentile'
                    
                    app.seg_params.(app.CallingApp.channel_str).cells.percentile = app.Percentile_value.Value;
                    app.CallingApp.BW = hist_thresholding(app.CallingApp.Filtered, app.seg_params.(app.CallingApp.channel_str).cells.percentile );
                    app.seg_steps(next).type='hist_thresholding';
                    
                case 'Local'
                    
                    app.seg_params.(app.CallingApp.channel_str).cells.percentile = app.LocalPercentile_value.Value;
                    app.CallingApp.BW = adaptive_thresholding(app.CallingApp.Filtered,app.CallingApp.Filtered,app.seg_params.(app.CallingApp.channel_str).cells);
                    app.seg_steps(next).type = 'Local';
                    
                    
            end
            
            % Now tack on a filtering step. 
            app.seg_params.(app.CallingApp.channel_str).cells.AbsMinVol = app.AbsMinVol.Value;
            app.seg_params.(app.CallingApp.channel_str).cells.AbsMaxVol = app.AbsMaxVol.Value;
            app.CallingApp.BW = filter_objects(app.CallingApp.BW,app.seg_params.(app.CallingApp.channel_str).cells.AbsMinVol);

        end

        % Value changed function: ClosefilterButton
        function close_button(app, event)
            app.seg_params.(app.CallingApp.channel_str).cells.imclose_r = app.imclose_radius_value.Value;
            app.CallingApp.BW = close_filter(app.CallingApp.BW, app.seg_params.(app.CallingApp.channel_str).cells.imclose_r);
            
            app.seg_steps(end+1).type = 'close_filter';
            
        end

        % Button pushed function: MeanfilterButton
        function mean_filter_button(app, event)
            app.seg_params.(app.CallingApp.channel_str).cells.MeanFilterSensitivity=app.SensitivityEditField.Value;
            % Check dimensions. 
            switch app.CallingApp.img_dim
                
                case 2
                    app.seg_params.(app.CallingApp.channel_str).cells.MeanFilterNeighborhood= app.mean_filter_xy_value.Value;
                    
                case 3
                    app.seg_params.(app.CallingApp.channel_str).cells.MeanFilterNeighborhood= [app.mean_filter_xy_value.Value,app.mean_filter_xy_value.Value,app.mean_filter_z_value.Value];

            end
            app.CallingApp.Image = mean_filter(app.CallingApp.Image,app.seg_params.(app.CallingApp.channel_str).cells.MeanFilterSensitivity, ...
            app.seg_params.(app.CallingApp.channel_str).cells.MeanFilterNeighborhood,app.CallingApp.image_bits);
            
            app.seg_steps(end+1).type = 'mean_filter';
            
        end

        % Button pushed function: RESETButton
        function RESET_button(app, event)
            app.seg_steps = app.initialize_seg_steps;
            
            app.CallingApp.update_stack();
        end

        % Value changed function: FilterOutliersButton
        function filter_outliers_button(app, event)
            app.seg_params.(app.CallingApp.channel_str).cells.OutlierThreshold = app.outlier_threshold_value.Value;
            app.seg_params.(app.CallingApp.channel_str).cells.MeanFilterNeighborhood= app.mean_filter_xy.Value;
            app.CallingApp.BW = filter_outliers(app.CallingApp.Image,app.CallingApp.BW,app.seg_params.(app.CallingApp.channel_str).cells.OutlierThreshold,app.seg_params.(app.CallingApp.channel_str).cells.MeanFilterNeighborhood);
            
            app.seg_steps(end+1).type = 'filter_outliers';
            
        end

        % Value changed function: FillholesButton
        function fill_holes_button(app, event)
            
            app.CallingApp.BW = fill_holes(app.CallingApp.BW);
            app.CallingApp.BW = filter_objects(app.CallingApp.BW, app.seg_params.(app.CallingApp.channel_str).cells.AbsMinVol);
            app.seg_steps(end+1).type = 'fill_holes';
            
        end

        % Value changed function: WatershedButton
        function watershed_button(app, event)
            app.seg_params.(app.CallingApp.channel_str).cells.nconnBW = 18;
            app.seg_params.(app.CallingApp.channel_str).cells.h_min_depth=app.hmindepthEditField.Value;
            app.seg_params.(app.CallingApp.channel_str).cells.h_min_conn=6;
            app.seg_params.(app.CallingApp.channel_str).cells.z_effect = 2;
            [app.CallingApp.BW,app.CallingApp.stats] = watershed_objects( ...
                app.CallingApp.BW, app.seg_params.(app.CallingApp.channel_str).cells.nconnBW,app.seg_params.(app.CallingApp.channel_str).cells.AbsMaxVol, ...
                app.seg_params.(app.CallingApp.channel_str).cells.h_min_depth, app.seg_params.(app.CallingApp.channel_str).cells.h_min_conn, ...
                app.seg_params.(app.CallingApp.channel_str).cells.z_effect,app.CallingApp.obj.exp_info.pixel_size);
            
            app.seg_steps(end+1).type = 'watershed';
            
            
        end

        % Value changed function: MergerButton
        function merger_button(app, event)
            
            app.seg_params.(app.CallingApp.channel_str).cells.merge_dist = app.mergedistEditField.Value;
            app.CallingApp.stats = merge_objects(app.CallingApp.stats,app.seg_params.(app.CallingApp.channel_str).cells.merge_dist, app.seg_params.(app.CallingApp.channel_str).cells.AbsMaxVol);
            
            app.seg_steps(end+1).type = 'merge_objects';
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SegmentCellsTab = uitab(app.TabGroup);
            app.SegmentCellsTab.Title = 'Segment Cells';
            app.ChannelEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.ChannelEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.ChannelEditField.Limits = [1 10];
            app.ChannelEditField.RoundFractionalValues = 'on';
            app.ChannelEditField.Value = 1;
            app.ClosefilterButton = uibutton(app.SegmentCellsTab, 'state');
            app.ClosefilterButton.ValueChangedFcn = createCallbackFcn(app, @close_button, true);
            app.ClosefilterButton.Text = 'Close filter';
            app.FilterOutliersButton = uibutton(app.SegmentCellsTab, 'state');
            app.FilterOutliersButton.ValueChangedFcn = createCallbackFcn(app, @filter_outliers_button, true);
            app.FilterOutliersButton.Text = 'Filter Outliers';
            app.FillholesButton = uibutton(app.SegmentCellsTab, 'state');
            app.FillholesButton.ValueChangedFcn = createCallbackFcn(app, @fill_holes_button, true);
            app.FillholesButton.Text = 'Fill holes';
            app.ThresholdingButtonGroup = uibuttongroup(app.SegmentCellsTab);
            app.ThresholdingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @thresholding_fn, true);
            app.ThresholdingButtonGroup.Title = 'Thresholding';
            app.SimpleThresh_value = uieditfield(app.ThresholdingButtonGroup, 'numeric');
            app.Percentile_value = uieditfield(app.ThresholdingButtonGroup, 'numeric');
            app.LocalPercentile_value = uieditfield(app.ThresholdingButtonGroup, 'numeric');
            app.SimpleButton = uibutton(app.ThresholdingButtonGroup, 'push');
            app.SimpleButton.ButtonPushedFcn = createCallbackFcn(app, @thresholding_fn, true);
            app.SimpleButton.Text = 'Simple';
            app.PercentileButton = uibutton(app.ThresholdingButtonGroup, 'push');
            app.PercentileButton.ButtonPushedFcn = createCallbackFcn(app, @thresholding_fn, true);
            app.PercentileButton.Text = 'Percentile';
            app.LocalButton = uibutton(app.ThresholdingButtonGroup, 'push');
            app.LocalButton.ButtonPushedFcn = createCallbackFcn(app, @thresholding_fn, true);
            app.LocalButton.Text = 'Local';
            app.AbsMaxVol = uieditfield(app.SegmentCellsTab, 'numeric');
            app.VolumesEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.VolumesEditFieldLabel.Text = 'Volumes';
            app.AbsMinVol = uieditfield(app.SegmentCellsTab, 'numeric');
            app.background_value = uieditfield(app.SegmentCellsTab, 'numeric');
            app.TileSizeEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.TileSizeEditFieldLabel.Text = 'Tile Size';
            app.TileSizeEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.ClipLimitEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.ClipLimitEditFieldLabel.Text = 'Clip Limit';
            app.ClipLimitEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.AlphaEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.BetaEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.EpsilonEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.EpsilonEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.SubBackChkbox = uicheckbox(app.SegmentCellsTab);
            app.SubBackChkbox.Text = {''; ''};
            app.CLAHEChkbox = uicheckbox(app.SegmentCellsTab);
            app.CLAHEChkbox.Text = {''; ''};
            app.CheckBox_3 = uicheckbox(app.SegmentCellsTab);
            app.CheckBox_3.Text = {''; ''};
            app.DeltaEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.DeltaEditFieldLabel.Text = 'Delta';
            app.DeltaEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.SubbackgroundButton = uibutton(app.SegmentCellsTab, 'push');
            app.SubbackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @sub_background, true);
            app.SubbackgroundButton.Text = 'Sub background';
            app.claheButton = uibutton(app.SegmentCellsTab, 'push');
            app.claheButton.ButtonPushedFcn = createCallbackFcn(app, @clahe_button, true);
            app.claheButton.Text = 'clahe';
            app.gradientimageButton = uibutton(app.SegmentCellsTab, 'push');
            app.gradientimageButton.ButtonPushedFcn = createCallbackFcn(app, @gradient_img, true);
            app.gradientimageButton.Text = 'gradient image';
            app.GammaEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.MedianFilterPanel = uipanel(app.SegmentCellsTab);
            app.MedianFilterPanel.Title = 'Median Filter';
            app.xyEditFieldLabel = uilabel(app.MedianFilterPanel);
            app.xyEditFieldLabel.Text = 'xy';
            app.xyEditField = uieditfield(app.MedianFilterPanel, 'numeric');
            app.zEditFieldLabel = uilabel(app.MedianFilterPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.MedianFilterPanel, 'numeric');
            app.SigmaGradientPanel = uipanel(app.SegmentCellsTab);
            app.SigmaGradientPanel.Title = 'Sigma Gradient';
            app.xyEditField_2Label = uilabel(app.SigmaGradientPanel);
            app.xyEditField_2Label.Text = 'xy';
            app.xyEditField_2 = uieditfield(app.SigmaGradientPanel, 'numeric');
            app.zEditField_2Label = uilabel(app.SigmaGradientPanel);
            app.zEditField_2Label.Text = 'z';
            app.zEditField_2 = uieditfield(app.SigmaGradientPanel, 'numeric');
            app.imclose_radius_value = uieditfield(app.SegmentCellsTab, 'numeric');
            app.MeanfilterButton = uibutton(app.SegmentCellsTab, 'push');
            app.MeanfilterButton.ButtonPushedFcn = createCallbackFcn(app, @mean_filter_button, true);
            app.MeanfilterButton.Text = 'Mean filter';
            app.SensitivityEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity';
            app.SensitivityEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.RESETButton = uibutton(app.SegmentCellsTab, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESET_button, true);
            app.RESETButton.Text = 'RESET';
            app.outlier_threshold_value = uieditfield(app.SegmentCellsTab, 'numeric');
            app.WatershedButton = uibutton(app.SegmentCellsTab, 'state');
            app.WatershedButton.ValueChangedFcn = createCallbackFcn(app, @watershed_button, true);
            app.WatershedButton.Text = 'Watershed';
            app.hmindepthEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.hmindepthEditFieldLabel.Text = 'hmin depth';
            app.hmindepthEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.MergerButton = uibutton(app.SegmentCellsTab, 'state');
            app.MergerButton.ValueChangedFcn = createCallbackFcn(app, @merger_button, true);
            app.MergerButton.Text = 'Merger';
            app.mergedistEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.mergedistEditFieldLabel.Text = 'merge dist';
            app.mergedistEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.xyEditField_3Label = uilabel(app.SegmentCellsTab);
            app.xyEditField_3Label.Text = 'xy';
            app.mean_filter_xy_value = uieditfield(app.SegmentCellsTab, 'numeric');
            app.zLabel = uilabel(app.SegmentCellsTab);
            app.zLabel.Text = 'z';
            app.mean_filter_z_value = uieditfield(app.SegmentCellsTab, 'numeric');
            app.SegmentSpotsTab = uitab(app.TabGroup);
            app.SegmentSpotsTab.Title = 'Segment Spots';
            app.TrackCellsTab = uitab(app.TabGroup);
            app.TrackCellsTab.Title = 'Track Cells';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tool_box(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end