classdef viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DisplayadjustmentLabel     matlab.ui.control.Label
        maxvalueEditField          matlab.ui.control.NumericEditField
        maxvalueEditFieldLabel     matlab.ui.control.Label
        minvalueEditField          matlab.ui.control.NumericEditField
        minvalueEditFieldLabel     matlab.ui.control.Label
        DisplayBWcontoursCheckBox  matlab.ui.control.CheckBox
        ZEditField                 matlab.ui.control.NumericEditField
        ThresholdEditField         matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel    matlab.ui.control.Label
        ThresholdCheckBox          matlab.ui.control.CheckBox
        ZSlider                    matlab.ui.control.Slider
        ZLabel                     matlab.ui.control.Label
        HistPlot                   matlab.ui.control.UIAxes
        SupportAxes2               matlab.ui.control.UIAxes
        imgAX                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        img % Description
        
        bw % binary image for plotting contours

        mask_bw % binary image of the mask applied to image. 
    end
    
    properties (Access = private)
        z_slices % Description
        initial_thresh % Description
        increment % Description
    end
    
    methods (Access = private)
        
        function [Rmn Rmx] = WL2R(app,W,L)
            Rmn = L - (W/2);
            Rmx = L + (W/2);
            if (Rmn >= Rmx)
                Rmx = Rmn + 1;
            end
        end
        
        function mouseScroll(app, ~, eventdata)
            UPDN = eventdata.VerticalScrollCount;
            Z = app.ZSlider.Value;
            Z = Z - UPDN;
            if (Z < 1)
                Z = 1;
            elseif (Z > app.ZSlider.Limits(2))
                Z = app.ZSlider.Limits(2);
            end
            app.ZSlider.Value=Z;
            app.update_image;
        end
        
        function [] = fh_kpfcn(app, H, E)
            
            % Figure keypressfcn
    
            %Check modifier. 
            if ~isempty(E.Modifier)
                %if shift. 
                if strcmp(E.Modifier{1},'shift')
                    inc = 0.1*app.increment;
                end  
            else
                inc = app.increment;
            end
            
            switch E.Key
                case 'uparrow'
                    
        
                    %Increase threshold. 
                    thresh_val = app.ThresholdEditField.Value;
                    new_val = thresh_val + inc;
                    app.ThresholdEditField.Value= new_val;
                    app.threshold_value_change()
        
                case 'downarrow'
        
                    %Decrease threshold. 
                    thresh_val =app.ThresholdEditField.Value;
                    new_val = thresh_val - inc;
                    app.ThresholdEditField.Value= new_val;
                    app.threshold_value_change()
        
                case 'leftarrow'

                    app.ZEditField.Value = app.ZEditField.Value -1;
                    if app.ZEditField.Value < 1
                        app.ZEditField.Value = 1;
                    end
                    app.ZVal_change()
                    app.ZSliderValueChanged()

                case 'rightarrow'
        
                    app.ZEditField.Value = app.ZEditField.Value + 1;
                    if app.ZEditField.Value > app.ZSlider.Limits(2)
                        app.ZEditField.Value = app.ZSlider.Limits(2);
                    end
                    app.ZVal_change()
                    app.ZSliderValueChanged()

            end
        end
        
        function results = func7(app)
            
        end
    end
    
    methods (Access = public)
        
        % When the whole stack is updated, update  histogram. 
        function update_stack(app,img, bw, mask)

            app.img = img;
            if length(size(app.img))==3
                app.ZSlider.Limits = [1,size(app.img,3)];
                app.ZSlider.Value = round( size(app.img,3)/2);
            else
                app.ZSlider.Limits = [1,1.1];
                app.ZSlider.Value  = 1;
            end

            % update histogram plot. 
            H = histogram(app.HistPlot,app.img(:),100);
            H.LineWidth = 2;
            app.HistPlot.YScale='log';

            % update percentile plot. 
            pcs=linspace(0,100,200);
            vals=prctile(app.img(:),pcs);
            plot(app.SupportAxes2,pcs,vals)

            if nargin > 1
                app.bw = bw;
                app.mask_bw = mask;
            end
            
            % set min/max to full scale. 
            app.minvalueEditField.Value=0;
            app.maxvalueEditField.Value=round(max(img(:)));
            % update image. 
            app.update_image;
            

        end


        function update_image(app)
            
            %Remove any existing contours. 
            H = findobj(app.imgAX,'Type','line');
            delete(H);

            % Display image. 

            %Update edit fields. 
            Rmin = app.minvalueEditField.Value;
            Rmax = app.maxvalueEditField.Value;

            imshow(app.img(:,:,app.ZSlider.Value),[Rmin Rmax],'Parent',app.imgAX);
            app.imgAX.Toolbar.HandleVisibility = 'off';
            app.imgAX.Toolbar.HandleVisibility = 'on';
            app.ZEditField.Value = app.ZSlider.Value;

            if(app.DisplayBWcontoursCheckBox.Value)
                % Plot contours of actively segmented objects. 
                app.plot_bw()

                %Check if mask was loaded, plot in different color as
                %needed. 
                
            end
            % Check if threshold box is checked. 
            hold(app.imgAX,"on")
            if app.ThresholdCheckBox.Value
                %Get threshold
                thresh_val =app.ThresholdEditField.Value;
                this_plane = app.img(:,:,app.ZSlider.Value);
                %p_val = findp(app.img(:),thresh_val);
                %disp("percentile value: ",p_val)

                bw = this_plane >= thresh_val;
                B = bwboundaries(bw,'noholes');

                % Plot boundaries as lines. 
                if length(B) > 5000
                    display('Threshold too low. Cannot display more contours!')
                else
                    for i = 1:length(B)
                        plot(app.imgAX,B{i}(:,2),B{i}(:,1),'-magenta','linewidth',2);
                    end
                end

            end

            hold(app.imgAX,"off")
            % recalculate the increment for thresholding. 
            vals = app.img(:);
            app.increment = 5* (max(vals)-min(vals))/100;

        end
        
        
        function plot_bw(app)
    
            %Remove any existing contours. 
            H = findobj(app.imgAX,'Type','line');
            delete(H);
            hold(app.imgAX,"on")


            % Plot boundaries as lines. 
            if ~isempty(app.bw)
                B = bwboundaries(app.bw(:,:,app.ZSlider.Value),'noholes');
                %%% Should really use stats to draw lines around each
                %%% segmen
                for i = 1:length(B)
                    plot(app.imgAX,B{i}(:,2),B{i}(:,1),'-r','linewidth',2);
                end
            else
                disp('No binary found for current segmentation.')
            end

            % Repeat for the mask layer. 
            if sum(app.mask_bw(:))
                M = bwboundaries(app.mask_bw(:,:,app.ZSlider.Value),'noholes');
                for i = 1:length(M)
                    plot(app.imgAX,M{i}(:,2),M{i}(:,1),'-','Color','green','linewidth',2);
                end
            end

            hold(app.imgAX,"off")

        end
        
        function reset_contrast(app)
            
            % set min/max to full scale. 
            app.minvalueEditField.Value=0;
            app.maxvalueEditField.Value=round(max(app.img(:)));
            app.display_contrast_change
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, img, varargin)

            % First get some info from the track_obj. 
            app.z_slices = size(img,3);  % number of slices
            app.ZSlider.Limits = [1,app.z_slices];
            app.ZSlider.Value = round(size(img,3)/2);
            
            % add image to app;
            app.img=img;
            vals = img(:);
            app.increment = 5* (max(vals)-min(vals))/100;

            % Update image. 
            app.update_image;

            %Setup some stuff. 
            app.UIFigure.WindowScrollWheelFcn=@app.mouseScroll;
            app.UIFigure.KeyPressFcn = @app.fh_kpfcn;
            
            p = inputParser;
            p.addParameter('zval',@isinteger);
            p.addParameter('initial_thresh',prctile(vals,90),@isinteger);
            p.addParameter('filter_on',false,@islogical);
            p.parse(varargin{:})

            if isfield(p.Results,"zval")
                S = p.Results.zval;
            end
            app.initial_thresh = p.Results.initial_thresh;
            app.ThresholdEditField.Value = app.initial_thresh;
            
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            app.ZSlider.Value = round(app.ZSlider.Value);
            app.ZEditField.Value = app.ZSlider.Value;
            app.update_image;
        end

        % Value changed function: ThresholdEditField
        function threshold_value_change(app, event)
            app.update_image;
            
            %Update histogram graphic with threshold.  
            Y = app.HistPlot.YLim;
            threshold = app.ThresholdEditField.Value;
            delete(findobj(app.HistPlot,'tag','threshold_line'));
            hold(app.HistPlot,"on")
            plot(app.HistPlot,[threshold; threshold],Y','-r','linewidth',2,'tag','threshold_line');
            hold(app.HistPlot,"off")

            X = app.SupportAxes2.XLim;
            delete(findobj(app.SupportAxes2,'tag','threshold_line'));
            hold(app.SupportAxes2,"on")
            plot(app.SupportAxes2,X',[threshold; threshold],'-r','linewidth',2,'tag','threshold_line');
            hold(app.SupportAxes2,"off")

        end

        % Value changed function: ZEditField
        function ZVal_change(app, event)
            app.ZSlider.Value = app.ZEditField.Value;
        end

        % Value changed function: DisplayBWcontoursCheckBox
        function BWdisplayChange(app, event)
            if app.DisplayBWcontoursCheckBox.Value
                app.plot_bw();
            else
                delete(findobj(app.imgAX,'Type','line'));
            end

        end

        % Value changed function: maxvalueEditField, minvalueEditField
        function display_contrast_change(app, event)
            Rmax = app.maxvalueEditField.Value;
            Rmin = app.minvalueEditField.Value;

            % update current image brightness. 
            imshow(app.img(:,:,app.ZSlider.Value),[Rmin Rmax],'Parent',app.imgAX);
            app.update_image
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.imgAX = uiaxes(app.UIFigure);
            title(app.imgAX, 'Current image')
            app.imgAX.XTick = [];
            app.imgAX.YTick = [];
            app.SupportAxes2 = uiaxes(app.UIFigure);
            title(app.SupportAxes2, 'Percentile')
            xlabel(app.SupportAxes2, 'X')
            ylabel(app.SupportAxes2, 'Y')
            zlabel(app.SupportAxes2, 'Z')
            app.HistPlot = uiaxes(app.UIFigure);
            title(app.HistPlot, 'Histogram')
            xlabel(app.HistPlot, 'Intensity')
            ylabel(app.HistPlot, 'Counts')
            zlabel(app.HistPlot, 'Z')
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z =';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.ThresholdCheckBox = uicheckbox(app.UIFigure);
            app.ThresholdCheckBox.Text = '';
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @threshold_value_change, true);
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZVal_change, true);
            app.DisplayBWcontoursCheckBox = uicheckbox(app.UIFigure);
            app.DisplayBWcontoursCheckBox.ValueChangedFcn = createCallbackFcn(app, @BWdisplayChange, true);
            app.DisplayBWcontoursCheckBox.Text = 'Display BW contours';
            app.minvalueEditFieldLabel = uilabel(app.UIFigure);
            app.minvalueEditFieldLabel.Text = 'min value';
            app.minvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.minvalueEditField.ValueChangedFcn = createCallbackFcn(app, @display_contrast_change, true);
            app.maxvalueEditFieldLabel = uilabel(app.UIFigure);
            app.maxvalueEditFieldLabel.Text = 'max value';
            app.maxvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.maxvalueEditField.ValueChangedFcn = createCallbackFcn(app, @display_contrast_change, true);
            app.DisplayadjustmentLabel = uilabel(app.UIFigure);
            app.DisplayadjustmentLabel.Text = 'Display adjustment';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = viewer(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end